classdef DialogDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UITable        matlab.ui.control.Table
        CancelButton   matlab.ui.control.Button
        ConfirmButton  matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp % Description
        db_subject_data;
        table_show;
        table_index;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.UIFigure.Name = 'DB INFO';
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            
            % Get DB info
            temp = {'LOAD', 'NEW', 'DELETE', 'NEW & DELETE'};
            app.db_subject_data = app.CallingApp.db_subject_data;
            app.table_show = {};app.table_index = [];
            for i=1:length(app.db_subject_data)
                table_temp = cell(max(1, length(app.db_subject_data(i).meg_file)), 1);
                table_temp(1, 1) = temp(app.db_subject_data(i).subject_status + 1);
                table_temp(1, 2) = {app.db_subject_data(i).subject_name};
                table_temp(1, 3) = {app.db_subject_data(i).subject_mri};
                table_temp(1, 4) = {app.db_subject_data(i).subject_fs};
                if(isempty(app.db_subject_data(i).meg_name))
                    table_temp(:, 5) = {' '};
                else
                    table_temp(:, 5) = app.db_subject_data(i).meg_name;
                end
                if(isempty(app.db_subject_data(i).meg_file))
                    table_temp(:, 5) = {' '};
                else
                    table_temp(:, 6) = app.db_subject_data(i).meg_file;
                end
                if(isempty(app.db_subject_data(i).meg_status))
                    table_temp(:, 5) = {' '};
                else
                    table_temp(:, 7) = temp(app.db_subject_data(i).meg_status + 1);
                end
                app.table_show = cat(1, app.table_show, table_temp);
                app.table_index = cat(1, app.table_index, [ones(size(table_temp, 1), 1) * i, (1:size(table_temp, 1))']);
            end
            app.UITable.Data = app.table_show;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            Output = app.CallingApp;
            delete(app)
            confirm_return(Output)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            Output = app.CallingApp;
            delete(app)
            confirm_return(Output)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 1;
            app.CallingApp.db_subject_data = app.db_subject_data;
            Output = app.CallingApp;
            delete(app)
            confirm_return(Output)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.UITable.ColumnFormat = repmat({'char'}, 1, size(app.UITable.Data, 2));
            if(size(indices, 1) == 1 && ~isempty(app.table_show{indices(1), indices(2)}))
                switch app.table_show{indices(1), indices(2)}
                    case 'LOAD'
                        app.UITable.ColumnFormat{indices(2)} = {'LOAD', 'DELETE'};
                    case 'NEW'
                        app.UITable.ColumnFormat{indices(2)} = {'NEW', 'NEW & DELETE'};
                    case 'DELETE'
                        app.UITable.ColumnFormat{indices(2)} = {'LOAD', 'DELETE'};
                    case 'NEW & DELETE'
                        app.UITable.ColumnFormat{indices(2)} = {'NEW', 'NEW & DELETE'};
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;newData = event.NewData;
            % modify meg/subject status
            if(size(indices, 1) == 1 && ~isempty(app.table_show{indices(1), indices(2)}))
                temp = {'LOAD', 'NEW', 'DELETE', 'NEW & DELETE'};
                temp = find(cellfun(@(x)strcmp(newData, x), temp)) - 1;
                if(indices(2) == 7)
                    app.db_subject_data(app.table_index(indices(1), 1)).meg_status(app.table_index(indices(1), 2)) = temp;
                elseif(indices(2) == 1)
                    app.db_subject_data(app.table_index(indices(1), 1)).subject_status = temp;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Subject Status'; 'Subject Name'; 'MEG File'; 'FS FIle'; 'MEG Name'; 'MEG FIle'; 'MEG Status'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'If new MEG files were added, all previous HFO results will be REMOVED after CLICK CONFIRM!!!!'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogDB(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end