classdef DialogEnterRemote < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        IPEditFieldLabel            matlab.ui.control.Label
        IPEditField                 matlab.ui.control.EditField
        UserNameEditFieldLabel      matlab.ui.control.Label
        UserNameEditField           matlab.ui.control.EditField
        PasswordEditFieldLabel      matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        ProjectPathEditFieldLabel   matlab.ui.control.Label
        ProjectPathEditField        matlab.ui.control.EditField
        Lamp                        matlab.ui.control.Lamp
        TestButton                  matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        MATLABPathEditField_2Label  matlab.ui.control.Label
        MATLABPathEditField         matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
    end
    
    properties (Access = public)
        ip % Description
        user_name
        passwd
        project_dir
        matlab_dir
        
        Process_isOK=0;
        db_subject_data;
        db_remote;
        sshpass_cmd;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.UIFigure.Name = 'Enter Remote Server';
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            app.sshpass_cmd = app.CallingApp.sshpass_cmd;
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Checking remote computer','Message','Checking remote computer','Indeterminate','on');
            error = TestRemote(app.matlab_dir, app.project_dir, app.user_name, app.passwd, app.ip, app.sshpass_cmd);
            close(wait_bar);
            if(isempty(error))
                app.Lamp.Color = [0.00,1.00,0.00];
                app.Label.Text = '';
            else
                app.Lamp.Color = [1.00,0.00,0.00];
                app.Label.Text = error;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Checking remote computer','Message','Checking path','Indeterminate','on');
            error = TestRemote(app.matlab_dir, app.project_dir, app.user_name, app.passwd, app.ip, app.sshpass_cmd);
            close(wait_bar);
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Checking remote computer','Message','Checking devices','Indeterminate','on');
            device = GetDevice(app.ip, app.user_name, app.passwd, app.sshpass_cmd);
            close(wait_bar);
            if(isempty(error) && ~isempty(device))
                % save db_remote
                app.db_remote = [];
                for i=1:length(device.Device)
                    db_remote_temp = struct('ip', '', 'user_name', '', 'passwd', '', 'project_dir', '', 'matlab_dir', '', ...
                                            'device_name', {}, 'device_id', {}); 
                    db_remote_temp(1).ip = app.ip;
                    db_remote_temp(1).user_name = app.user_name;
                    db_remote_temp(1).passwd = app.passwd;
                    db_remote_temp(1).project_dir = fullfile(app.project_dir, ['Device', num2str(device.DeviceID(i))]);
                    db_remote_temp(1).matlab_dir = app.matlab_dir;
                    db_remote_temp(1).device_name = device.Device{i};
                    db_remote_temp(1).device_id = device.DeviceID(i);
                    app.db_remote = cat(1, app.db_remote, db_remote_temp);
                    
                end
                app.CallingApp.db_remote = app.db_remote;
                app.CallingApp.GridLayout.Visible = 1;
                app.CallingApp.Process_isOK = 1;
                Output = app.CallingApp;
            else
                app.CallingApp.GridLayout.Visible = 1;
                app.CallingApp.Process_isOK = 0;
                Output = app.CallingApp;
            end
            delete(app);
            RemoteReturn(Output);
        end

        % Value changing function: IPEditField
        function IPEditFieldValueChanging(app, event)
            app.ip = event.Value;
        end

        % Value changing function: UserNameEditField
        function UserNameEditFieldValueChanging(app, event)
            app.user_name = event.Value;
        end

        % Value changing function: ProjectPathEditField
        function ProjectPathEditFieldValueChanging(app, event)
            app.project_dir = event.Value;
        end

        % Value changing function: MATLABPathEditField
        function MATLABPathEditFieldValueChanging(app, event)
            app.matlab_dir = event.Value;
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            app.passwd = event.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.IPEditFieldLabel = uilabel(app.GridLayout2);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.GridLayout2, 'text');
            app.IPEditField.ValueChangingFcn = createCallbackFcn(app, @IPEditFieldValueChanging, true);
            app.UserNameEditFieldLabel = uilabel(app.GridLayout2);
            app.UserNameEditFieldLabel.Text = 'User Name';
            app.UserNameEditField = uieditfield(app.GridLayout2, 'text');
            app.UserNameEditField.ValueChangingFcn = createCallbackFcn(app, @UserNameEditFieldValueChanging, true);
            app.PasswordEditFieldLabel = uilabel(app.GridLayout2);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.GridLayout2, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.ProjectPathEditFieldLabel = uilabel(app.GridLayout2);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.GridLayout2, 'text');
            app.ProjectPathEditField.ValueChangingFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanging, true);
            app.Lamp = uilamp(app.GridLayout2);
            app.TestButton = uibutton(app.GridLayout2, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.OKButton = uibutton(app.GridLayout2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MATLABPathEditField_2Label = uilabel(app.GridLayout2);
            app.MATLABPathEditField_2Label.Text = 'MATLAB Path';
            app.MATLABPathEditField = uieditfield(app.GridLayout2, 'text');
            app.MATLABPathEditField.ValueChangingFcn = createCallbackFcn(app, @MATLABPathEditFieldValueChanging, true);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogEnterRemote(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end