classdef DialogGroupProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        PanelSubject              matlab.ui.container.Panel
        GridLayoutSubject         matlab.ui.container.GridLayout
        PanelSubjectRemain        matlab.ui.container.Panel
        GridLayoutSubjectRemain   matlab.ui.container.GridLayout
        ListBoxSubjectRemain      matlab.ui.control.ListBox
        PanelSubjectMEG           matlab.ui.container.Panel
        GridLayoutSubjectMEG      matlab.ui.container.GridLayout
        ListBoxSubjectMEG         matlab.ui.control.ListBox
        PanelSubjectProcess       matlab.ui.container.Panel
        GridLayoutSubjectProcess  matlab.ui.container.GridLayout
        ListBoxSubjectProcess     matlab.ui.control.ListBox
        ButtonAddSubject          matlab.ui.control.Button
        ButtonRemoveSubject       matlab.ui.control.Button
        PanelParam                matlab.ui.container.Panel
        GridLayoutParam           matlab.ui.container.GridLayout
        ButtonParam               matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
        PanelNetwork              matlab.ui.container.Panel
        GridLayoutNetwork         matlab.ui.container.GridLayout
        ButtonAddServer           matlab.ui.control.Button
        ButtonTestServer          matlab.ui.control.Button
        PanelServerRemain         matlab.ui.container.Panel
        GridLayoutServerRemain    matlab.ui.container.GridLayout
        ListBoxServerRemain       matlab.ui.control.ListBox
        PanelServerProcess        matlab.ui.container.Panel
        GridLayoutServerProcess   matlab.ui.container.GridLayout
        ListBoxServerProcess      matlab.ui.control.ListBox
        ButtonRemove              matlab.ui.control.Button
        ButtonAdd                 matlab.ui.control.Button
        PanelisNetwork            matlab.ui.container.Panel
        GridLayoutisNetwork       matlab.ui.container.GridLayout
        ButtonUsingNetwork        matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        CallingApp 
        
        DialogProcessAll;
        
        subject_show;
        GroupProcessIsNet=0;
    end
    
    properties (Access = public)
        IEDdetectionOpt;
        VirtualSensorOpt;
        HFOdetectionOpt;
        PreprocessOpt;
        
        Process_isOK=0;
        Interupt;
        
        db_remote;
        db_remote_all;
        db_remote_process;
        db_remote_remain;
        
        db_local_all;
        db_local_process;
        db_local_remain;
        
        db_subject_all;
        db_subject_process;
        db_subject_remain;
        
        db_process;
        sshpass_cmd;
    end
    
    methods (Access = private)
        
        % check process state
        function CheckProcessState(app)
            project_dir = app.CallingApp.ProjectDir;
            for i=1:size(app.db_subject_all,1)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message',['Geting State: ',num2str(i),'/',num2str(size(app.db_subject_all,1))],'value',i/size(app.db_subject_all,1));
                if(any(cellfun(@(x)~exist(fullfile(project_dir, x, 'SaveHFOViewResults.mat') ,'file'), app.db_subject_all.results)) || ...
                   any(cellfun(@(x)~exist(fullfile(project_dir, x, 'SaveVirtualSensorResults.mat') ,'file'), app.db_subject_all.results)) || ...
                   any(cellfun(@(x)~exist(fullfile(project_dir, x, 'SaveSpikeViewResults.mat') ,'file'), app.db_subject_all.results)))
                    app.subject_show{i} = cat(2, 'U: ', app.db_subject_all(i).subject_name);
                else
                    app.subject_show{i} = cat(2, 'P: ', app.db_subject_all(i).subject_name);
                end
                close(wait_bar);               
            end
        end      
        
        % check subject data (mri and meg data)
        function CheckSubjectData(app)
            project_dir = app.CallingApp.ProjectDir;
            for i=1:length(app.CallingApp.db_subject_data)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Checking Subject data','Message',['Subject: ',num2str(i),'/',num2str(length(app.CallingApp.db_subject_data))],'value',i/length(app.CallingApp.db_subject_data));
                % check subject name
                if(isempty(app.CallingApp.db_subject_data(i).subject_name))
                    app.db_subject_all(i) = [];
                    continue
                end
                % check meg data
                if(isempty(app.CallingApp.db_subject_data(i).meg_file))
                    app.db_subject_all(i) = [];
                    continue
                else
                    remove_index = [];
                    for fif=1:length(app.CallingApp.db_subject_data(i).meg_file)
                        % check meg data (fif_name, fif_path, channel_mat, result_path)
                        if(isempty(app.db_subject_all(i).meg_file{fif}) || ...
                           ~exist(fullfile(project_dir, app.db_subject_all(i).meg_file{fif}), 'file') || ...
                           isempty(app.db_subject_all(i).meg_name{fif}) || ...
                           isempty(app.db_subject_all(i).results{fif}) || ...
                           isempty(app.db_subject_all(i).channel_mat(fif)))
                           remove_index = cat(1, remove_index, fif);
                        end
                    end
                    app.db_subject_all(i).meg_file(remove_index) = [];
                    app.db_subject_all(i).meg_name(remove_index) = [];
                    app.db_subject_all(i).results(remove_index) = [];
                    app.db_subject_all(i).channel_mat(remove_index) = [];
                    app.db_subject_all(i).meg_status(remove_index) = [];
                    if(isempty(app.CallingApp.db_subject_data(i).meg_file))
                        app.db_subject_all(i) = [];
                        continue
                    end
                end
                close(wait_bar);
            end
        end
        
        % update local device list table
        function UpdateDeviceListLocal(app)
            % check db_local
            local_info_process = cellfun(@(x)x.device_name, num2cell(app.db_local_process), 'UniformOutput', false);
            local_info_all = cellfun(@(x)x.device_name, num2cell(app.db_local_all), 'UniformOutput', false);
            % check db_local process
            process_index = cellfun(@(x)any(cellfun(@(y)strcmp(y, x), local_info_process)), local_info_all);
            app.db_local_process = app.db_local_all(process_index);
            % check db_local remain (in all, not in process)
            local_info_process = cellfun(@(x)x.device_name, num2cell(app.db_local_process), 'UniformOutput', false);
            remian_index = cellfun(@(x)all(cellfun(@(y)~strcmp(y, x), local_info_process)), local_info_all);
            app.db_local_remain = app.db_local_all(remian_index);
            
            % get device of process 
            temp = cellfun(@(x)[x.device_name, ': Local'], num2cell(app.db_local_process), 'UniformOutput', false);
            [~, index] = sort(temp);
            app.ListBoxServerProcess.Items = temp(index);
            app.db_local_process = app.db_local_process(index);
            
            % get device of remain 
            temp = cellfun(@(x)[x.device_name, ': Local'], num2cell(app.db_local_remain), 'UniformOutput', false);
            [~, index] = sort(temp);
            app.ListBoxServerRemain.Items = temp(index);
            app.db_local_remain = app.db_local_remain(index);
        end
        
        % update IP list table
        function UpdateIPListRemote(app)
            % check db_remote
            remote_info_process = cellfun(@(x)cat(2, x.ip, x.device_name), num2cell(app.db_remote_process), 'UniformOutput', false);
            remote_info_all = cellfun(@(x)cat(2, x.ip, x.device_name), num2cell(app.db_remote_all), 'UniformOutput', false);
            % check db_remote process
            process_index = cellfun(@(x)any(cellfun(@(y)strcmp(y, x), remote_info_process)), remote_info_all);
            app.db_remote_process = app.db_remote_all(process_index);
            % check db_remote remain (in all, not in process)
            remote_info_process = cellfun(@(x)cat(2, x.ip, x.device_name), num2cell(app.db_remote_process), 'UniformOutput', false);
            remian_index = cellfun(@(x)all(cellfun(@(y)~strcmp(y, x), remote_info_process)), remote_info_all);
            app.db_remote_remain = app.db_remote_all(remian_index);
            
            % get ip of process server
            temp = cellfun(@(x)[x.device_name, ': ', x.ip], num2cell(app.db_remote_process), 'UniformOutput', false);
            [~, index] = sort(temp);
            app.ListBoxServerProcess.Items = temp(index);
            app.db_remote_process = app.db_remote_process(index);
            
            % get ip of remain server
            temp = cellfun(@(x)[x.device_name, ': ', x.ip], num2cell(app.db_remote_remain), 'UniformOutput', false);
            [~, index] = sort(temp);
            app.ListBoxServerRemain.Items = temp(index);
            app.db_remote_remain = app.db_remote_remain(index);
        end
        
        % update Subject list table
        function UpdateSubjectList(app)
            % check db_subject
            subject_process = {app.db_subject_process.subject_name};
            subject_all = {app.db_subject_all.subject_name};
            % check db_subject process
            process_index = cellfun(@(x)any(cellfun(@(y)strcmp(y, x), subject_process)), subject_all);
            subject_process_name = app.subject_show(process_index);
            app.db_subject_process = app.db_subject_all(process_index);
            % check db_subject remain (in all, not in process)
            subject_process = {app.db_subject_process.subject_name};
            remian_index = cellfun(@(x)all(cellfun(@(y)~strcmp(y, x), subject_process)), subject_all);
            subject_remain_name = app.subject_show(remian_index);
            app.db_subject_remain = app.db_subject_all(remian_index);
            
            % get name of process subject data
            [~, index] = sort(subject_process_name);
            app.ListBoxSubjectProcess.Items = subject_process_name(index);
            app.db_subject_process = app.db_subject_process(index);
            
            % get name of remain subject data
            [~, index] = sort(subject_remain_name);
            app.ListBoxSubjectRemain.Items = subject_remain_name(index);
            app.db_subject_remain = app.db_subject_remain(index);
            % show meg file
            if(~isempty(app.ListBoxSubjectRemain.Value))
                select_index = cellfun(@(x)find(contains(app.ListBoxSubjectRemain.Items, x)), app.ListBoxSubjectRemain.Value);
                app.ListBoxSubjectMEG.Items = app.db_subject_remain(select_index).meg_name;
            else
                app.ListBoxSubjectMEG.Items = {};
            end
        end
        
        % Generate Struct data of process for main function
        function is_done = GenerateProcessStruct(app)
            is_done = 0;
            app.db_process = struct('is_remote', 0, 'process_info', []);
            % check process subject data
            if(isempty(app.db_subject_process))
                uialert(app.UIFigure, 'No SUBJECT was SELECTED to process!', 'Input error');
                return;
            end
            if(~app.GroupProcessIsNet)
                % if local
                app.db_process.is_remote = 0;
                if(isempty(app.db_local_process))
                    uialert(app.UIFigure, 'No Local device was SELECTED!', 'Input error');
                    return;
                end
                % allocate subject to each device 
                device_num = length(app.db_local_process);
                subject_num = length(app.db_subject_process);
                device_allocated_subject = cell(device_num, 1);
                for dev=1:device_num
                    device_allocated_subject{dev} = (dev-1)*ceil(subject_num/device_num)+1 : min(subject_num,dev*ceil(subject_num/device_num));
                end
                % generate process struct
                process_info = [];
                for dev=1:length(device_num)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Generating data','Message',['Device: ',num2str(dev),'/',num2str(length(device_num)), '; Generate local process data'],'value',dev/length(device_num));
                    if(~isempty(device_allocated_subject{dev}))
                        % get process info
                        process_info_temp = struct('project_dir', '', 'matlab_dir', '', 'device', -1, 'process_data', [],...
                                                   'pre_process_opt', [], 'ied_detection_opt', [], 'virtual_sensor_opt', [], 'hfo_detection_opt', [], 'cmd', [], 'stdout_dir', []);
                        process_info_temp.project_dir = app.db_local_process(dev).project_dir;
                        process_info_temp.matlab_dir = app.db_local_process(dev).matlab_dir;
                        process_info_temp.device = app.db_local_process(dev).device_id;
                        process_info_temp.stdout_dir = fullfile(process_info_temp.project_dir, 'RunningData', 'Logs', ['Device', num2str(process_info_temp.device), '_stdout.txt']);
                        % generate input parameters
                        process_info_temp.pre_process_opt = app.PreprocessOpt;
                        process_info_temp.ied_detection_opt = app.IEDdetectionOpt;
                        process_info_temp.virtual_sensor_opt = app.VirtualSensorOpt;
                        process_info_temp.hfo_detection_opt = app.HFOdetectionOpt;
                        % get process data 
                        % parameters: 0 -> to be processed; 1 -> finshed; 3 -> error;
                        process_data = [];
                        index = device_allocated_subject{dev};
                        for subj=1:length(index)
                            process_data_temp = struct('subject_name', '', 'meg_name', {}, 'meg_path', {}, ...
                                                       'meg_channel_mat', [], 'result_path', {}, 'process_status', [], 'log_dir', [], 'post_cmd', []);
                            process_data_temp(1).subject_name = app.db_subject_process(index(subj)).subject_name;
                            process_data_temp(1).meg_name = app.db_subject_process(index(subj)).meg_name;
                            process_data_temp(1).meg_path = cellfun(@(x)fullfile(app.db_local_process(dev).project_dir, x), app.db_subject_process(index(subj)).meg_file, 'UniformOutput', false);
                            process_data_temp(1).meg_channel_mat = app.db_subject_process(index(subj)).channel_mat;
                            process_data_temp(1).result_path = cellfun(@(x)fullfile(app.db_local_process(dev).project_dir, x), app.db_subject_process(index(subj)).results, 'UniformOutput', false);
                            process_data_temp(1).process_status = 0;
                            process_data_temp(1).log_dir = fullfile(app.db_remote_process(dev).project_dir, 'RunningData', 'Logs', [app.db_subject_process(index(subj)).subject_name, '_log.txt']);
                            process_data = cat(1, process_data, process_data_temp);
                        end
                        process_info_temp.process_data = process_data;
                        process_info = cat(1, process_info, process_info_temp);
                    end
                    close(wait_bar);
                end
                app.db_process.process_info = process_info;
            else
                % if remote
                app.db_process.is_remote = 1;
                if(isempty(app.db_remote_process))
                    uialert(app.UIFigure, 'No Local device was SELECTED!', 'Input error');
                    return;
                end
                % allocate subject to each remote 
                remote_num = length(app.db_remote_process);
                subject_num = length(app.db_subject_process);
                device_allocated_subject = cell(remote_num, 1);
                for dev=1:remote_num
                    device_allocated_subject{dev} = (dev-1)*ceil(subject_num/remote_num)+1 : min(subject_num,dev*ceil(subject_num/remote_num));
                end
                % generate process struct
                process_info = [];
                for dev=1:length(remote_num)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Generating data','Message',['Device: ',num2str(dev),'/',num2str(length(remote_num)), '; Generate remote process data'],'value',dev/length(remote_num));
                    if(~isempty(device_allocated_subject{dev}))
                        index = device_allocated_subject{dev};
                        % get process info
                        process_info_temp = struct('ip', '', 'user_name', '', 'passwd', '', 'project_dir', '', ...
                                                   'cfg', [], 'matlab_dir', '', 'device', -1, 'process_data', [],...
                                                   'pre_process_opt', [], 'ied_detection_opt', [], 'virtual_sensor_opt', [], 'hfo_detection_opt', [], 'cmd', [], 'stdout_dir', []);
                        process_info_temp.ip = app.db_remote_process(dev).ip;
                        process_info_temp.user_name = app.db_remote_process(dev).user_name;
                        process_info_temp.passwd = app.db_remote_process(dev).passwd;
                        process_info_temp.project_dir = app.db_remote_process(dev).project_dir;
                        process_info_temp.matlab_dir = app.db_remote_process(dev).matlab_dir;
                        process_info_temp.device = app.db_remote_process(dev).device_id;
                        process_info_temp.stdout_dir = fullfile(process_info_temp.project_dir, 'RunningData', 'Logs', ['Device', num2str(process_info_temp.device), '_stdout.txt']);
                        % generate input parameters
                        process_info_temp.pre_process_opt = app.PreprocessOpt;
                        process_info_temp.ied_detection_opt = app.IEDdetectionOpt;
                        process_info_temp.virtual_sensor_opt = app.VirtualSensorOpt;
                        process_info_temp.hfo_detection_opt = app.HFOdetectionOpt;
                        % get cfg
                        cfg.BrainstormDbDir = 'bstProtocol';cfg.ProjectDir = process_info_temp.project_dir;cfg.db_subject_data = app.db_subject_process(index);
                        process_info_temp.cfg = cfg;
                        % get process data 
                        % parameters: 0 -> to be processed; 1 -> finshed; 2 -> download; 3 -> error;
                        process_data = [];
                        for subj=1:length(index)
                            process_data_temp = struct('subject_name', '', 'meg_name', {}, 'meg_path', {}, ...
                                                       'meg_channel_mat', [], 'result_path', {}, 'process_status', [], 'log_dir', [], 'post_cmd', []);
                            process_data_temp(1).subject_name = app.db_subject_process(index(subj)).subject_name;
                            process_data_temp(1).meg_name = app.db_subject_process(index(subj)).meg_name;
                            process_data_temp(1).meg_path = fullfile(app.db_remote_process(dev).project_dir, app.db_subject_process(index(subj)).meg_file);
                            process_data_temp(1).meg_channel_mat = app.db_subject_process(index(subj)).channel_mat;
                            process_data_temp(1).result_path = fullfile(app.db_remote_process(dev).project_dir, app.db_subject_process(index(subj)).results);
                            process_data_temp(1).process_status = 0;
                            process_data_temp(1).log_dir = fullfile(app.db_remote_process(dev).project_dir, 'RunningData', 'Logs', [app.db_subject_process(index(subj)).subject_name, '_log.txt']);
                            process_data = cat(1, process_data, process_data_temp);
                        end
                        process_info_temp.process_data = process_data;
                        process_info = cat(1, process_info, process_info_temp);
                    end
                    close(wait_bar);
                end
                app.db_process.process_info = process_info;
            end
            % final check
            if(~isempty(app.db_process.process_info))
                is_done = 1;
            end
        end
        
        % generate data of process
        function GenerateShellCMD(app)
            if(app.GroupProcessIsNet)
                project_dir = app.CallingApp.ProjectDir;
                % make dir of remote path in local project
                remote_path_local = fullfile(project_dir, 'RunningData', 'RemoteDB');
                if(~exist(remote_path_local, 'file'))
                    mkdir(remote_path_local)
                end
                % generate shell commond for each remote process
                for proc=1:length(app.db_process.process_info)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Generating data','Message',['Device: ',num2str(proc),'/',num2str(length(app.db_process.process_info)),...
                                             '; Generate shell cmd for remote process'],'value',proc/length(app.db_process.process_info));
                    process_info = app.db_process.process_info(proc);
                    % generate cmd before processing data (copy file to remote server)
                    ssh_mkdir_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' ssh ', process_info.user_name,'@', process_info.ip, ' ', ...
                                     '"mkdir -p ', process_info.project_dir, '";'];
                    bd_remote_path_local = fullfile(remote_path_local, [strrep(process_info.ip, '.', '_'), '_Device', num2str(process_info.device)]);
                    db_scp_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' scp -r ', bd_remote_path_local, '/* ',...
                                  process_info.user_name,'@', process_info.ip, ':', process_info.project_dir, ';'];
                    bst_scp_cmd = '';meg_scp_cmd = '';
                    for subj=1:length(process_info.process_data)
                        local_bst = fullfile(project_dir, 'bstProtocol', process_info.process_data(subj).subject_name);
                        remote_bst = fullfile(process_info.project_dir, 'bstProtocol');
                        bst_scp_cmd = cat(2, bst_scp_cmd, [app.sshpass_cmd, ' -p ', process_info.passwd,' scp -r ', local_bst, ' ',...
                                                           process_info.user_name,'@', process_info.ip, ':', remote_bst, ';']);
                        for fif=1:length(process_info.process_data(subj).meg_name)
                            local_meg = strrep(process_info.process_data(subj).meg_path{fif}, process_info.project_dir, project_dir);
                            remote_meg = fileparts(process_info.process_data(subj).meg_path{fif});
                            meg_scp_cmd = cat(2, meg_scp_cmd, [app.sshpass_cmd, ' -p ', process_info.passwd,' scp ', local_meg, ' ',...
                                                               process_info.user_name,'@', process_info.ip, ':', remote_meg, ';']);
                        end
                    end
                    pre_cmd = cat(2, ssh_mkdir_cmd, db_scp_cmd, bst_scp_cmd, meg_scp_cmd);
                    % generate process cmd
                    kill_screen_cmd = ['x=EMHapp_', num2str(process_info.device), ';temp="screen -ls ${x}";',...
                                       'if [[ $temp == *"No Sockets found"* ]]; then screen -S ${x}; else screen -S ${x} -X quit; fi;'];
                    ssh_kill_screen_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' ssh ', process_info.user_name,'@', process_info.ip, ' ', ...
                                           '''', kill_screen_cmd, ''''];
                    matlab_cmd = [process_info.matlab_dir, ' -nosplash -nodesktop -nodisplay -r ',...
                                  '\"addpath ', fullfile(process_info.project_dir, 'RunningData', 'Fun', 'GroupProcess'), ';run GroupProcess\"'];
                    screen_matlab_cmd = ['screen -S EMHapp_', num2str(process_info.device), ' -dm bash -c ', '"', 'cd ', process_info.project_dir, ';', matlab_cmd, '; exec bash', '"'];
                    ssh_screen_matlab_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' ssh ', process_info.user_name,'@', process_info.ip, ' ', ...
                                             '''', screen_matlab_cmd, ''''];
                    process_cmd = cat(2, ssh_kill_screen_cmd, ';', ssh_screen_matlab_cmd);
                    % generate process status checking cmd
                    local_cfg = fullfile(bd_remote_path_local, 'cfg.mat');
                    remote_cfg = fullfile(process_info.project_dir, 'cfg.mat');
                    cfg_scp_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' scp ',...
                                   process_info.user_name,'@', process_info.ip, ':', remote_cfg, ' ', local_cfg, ';'];
                    local_log = fullfile(bd_remote_path_local, 'RunningData', 'Logs');
                    remote_log = fullfile(process_info.project_dir, 'RunningData', 'Logs');
                    log_scp_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' scp -r ',...
                                   process_info.user_name,'@', process_info.ip, ':', remote_log, ' ', fileparts(local_log), ';'];
                    status_refresh_1 = cat(2, log_scp_cmd, cfg_scp_cmd);
                    log_scp_cmd_upload = [app.sshpass_cmd, ' -p ', process_info.passwd,' scp ',...
                                          local_cfg, ' ', process_info.user_name,'@', process_info.ip, ':', remote_cfg, ';'];
                    status_refresh_2 = log_scp_cmd_upload;
                    % add to process_info
                    cmd.pre_cmd = pre_cmd;cmd.process_cmd = process_cmd;cmd.status_refresh_1 = status_refresh_1;cmd.status_refresh_2 = status_refresh_2;
                    app.db_process.process_info(proc).cmd = cmd;
                    % generate cmd after processing data (copy file from remote server to local for each subject)
                    for subj=1:length(process_info.process_data)
                        process_data = process_info.process_data;
                        result_scp_cmd = '';meg_scp_cmd = '';
                        for fif=1:length(process_info.process_data(subj).meg_name)
                            local_meg = fullfile(project_dir, process_data(subj).subject_name, 'MEG');
                            remote_meg = [strrep(strrep(process_data(subj).meg_path{fif}, '_tsss', ''), '.fif', ''), '_tsss.fif'];
                            meg_scp_cmd = cat(2, meg_scp_cmd, [app.sshpass_cmd, ' -p ', process_info.passwd,' scp ',...
                                                               process_info.user_name,'@', process_info.ip, ':', remote_meg, ' ', local_meg, ';']);
                            local_result = strrep(process_data(subj).result_path{fif}, process_info.project_dir, project_dir);
                            remote_result = process_data(subj).result_path{fif};
                            result_scp_cmd = cat(2, result_scp_cmd, [app.sshpass_cmd, ' -p ', process_info.passwd,' scp -r ',...
                                                                     process_info.user_name,'@', process_info.ip, ':', remote_result, ' ', fileparts(local_result), ';']);
                        end
                        app.db_process.process_info(proc).process_data(subj).post_cmd = cat(2, result_scp_cmd, meg_scp_cmd);
                    end
                    % make dir of remote process 
                    if(exist(bd_remote_path_local, 'file'))
                        rmdir(bd_remote_path_local, 's');
                    end
                    mkdir(bd_remote_path_local);
                    mkdir(fullfile(bd_remote_path_local, 'bstProtocol'));
                    mkdir(fullfile(bd_remote_path_local, 'RunningData', 'Fun'));
                    mkdir(fullfile(bd_remote_path_local, 'RunningData', 'Logs'));
                    copyfile(fullfile(project_dir, 'RunningData', 'Fun'), fullfile(bd_remote_path_local, 'RunningData', 'Fun'));
                    % save cfg file
                    db_process_temp.is_remote = app.db_process.is_remote;db_process_temp.process_info = app.db_process.process_info(proc);
                    temp = process_info.cfg;temp.db_process = db_process_temp;
                    save(fullfile(bd_remote_path_local, 'cfg.mat'), '-struct', 'temp');
                    for subj=1:length(process_info.process_data)
                        mkdir(fullfile(bd_remote_path_local, process_info.process_data(subj).subject_name));
                        mkdir(fullfile(bd_remote_path_local, process_info.process_data(subj).subject_name, 'MEG'));
                        mkdir(fullfile(bd_remote_path_local, process_info.process_data(subj).subject_name, 'Results'));
                        for fif=1:length(process_info.process_data(subj).meg_name)
                            mkdir(fullfile(bd_remote_path_local, process_info.process_data(subj).subject_name, 'Results'), process_info.process_data(subj).meg_name{fif});
                        end
                    end
                    close(wait_bar)
                end
            else
                % make dir of local process 
                local_path = fullfile(app.CallingApp.ProjectDir, 'RunningData', 'LocalDB');
                if(exist(local_path, 'file'))
                    rmdir(local_path, 's');
                end
                mkdir(local_path);
                % generate shell commond for each local process
                for proc=1:length(app.db_process.process_info)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Generating data','Message',['Device: ',num2str(proc),'/',num2str(length(app.db_process.process_info)),...
                                             '; Generate shell cmd for local process'],'value',proc/length(app.db_process.process_info));
                    process_info = app.db_process.process_info(proc);
                    % make dir
                    local_path_temp = fullfile(local_path, ['Device', num2str(process_info.device)]);
                    mkdir(local_path_temp);
                    % generate process cmd
                    kill_screen_cmd = ['x=EMHapp_', num2str(process_info.device), ';temp="screen -ls ${x}";',...
                                       'if [[ $temp == *"No Sockets found"* ]]; then screen -S ${x}; else screen -S ${x} -X quit; fi;'];
                    matlab_cmd = [process_info.matlab_dir, ' -nosplash -nodesktop -nodisplay -r ',...
                                  '\"addpath ', fullfile(process_info.project_dir, 'RunningData', 'Fun', 'GroupProcess'), ';run GroupProcess\"'];
                    screen_matlab_cmd = ['screen -S EMHapp_', num2str(process_info.device), ' -dm bash -c ', '''', 'cd ', local_path_temp, ';', matlab_cmd, '; exec bash', ''''];
                    process_cmd = cat(2, kill_screen_cmd, ';', screen_matlab_cmd);
                    % add to process_info
                    cmd.process_cmd = process_cmd;
                    app.db_process.process_info(proc).cmd = cmd;
                    % save cfg file 
                    db_process_temp.is_remote = app.db_process.is_remote;db_process_temp.process_info = app.db_process.process_info(proc);
                    temp = load(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'));temp.db_process = db_process_temp;
                    save(fullfile(local_path_temp, 'cfg.mat'), '-struct', 'temp');
                    close(wait_bar)
                end
            end
        end
        
        % save db_process and db_remote cfg.mat
        function is_done = SaveCfgMat(app)
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Saving','Message','Save cfg mat file','Indeterminate','on');
            is_done = 0;
            % step1: load cfg.mat
            if(exist(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'), 'file'))
                save_mat = load(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'));
            else
                return
            end
            % step2: make save struct
            save_mat.db_remote = app.db_remote_all;
            save_mat.db_process = app.db_process;
            % step3: save struct 
            save(fullfile(save_mat.ProjectDir, 'cfg.mat'), '-struct', 'save_mat');
            close(wait_bar);
        end
    end
    
    methods (Access = public)
        
        % return of DialogProcess
        function ProcessReturn(app)
            CheckSubjectData(app);
            CheckProcessState(app);
            UpdateSubjectList(app);
        end
        
        % return of DialogEnterRemote
        function RemoteReturn(app)
            if(app.Process_isOK && ~isempty(app.db_remote))
                % check repeat remote and add new remote
                remote_info_new = cellfun(@(x)cat(2, x.ip, x.device_name), num2cell(app.db_remote), 'UniformOutput', false);
                remote_info_all = cellfun(@(x)cat(2, x.ip, x.device_name), num2cell(app.db_remote_all), 'UniformOutput', false);
                for i=1:length(remote_info_new)
                    if(isempty(remote_info_all) || all(cellfun(@(x)~strcmp(x, remote_info_new{i}), remote_info_all)))
                        app.db_remote_all = cat(1, app.db_remote_all, app.db_remote(i));
                    end
                end
                % show all
                UpdateIPListRemote(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = 'Process Data';
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp; 
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            app.sshpass_cmd = app.CallingApp.sshpass_cmd;
            
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Process','Message','Process Init','Indeterminate','on');
            % check local 
            device = GetDevice();
            db_remote_test = [];
            if(~isempty(device))
                % get available db_local
                db_local_temp = [];
                if(length(device.DeviceID) == 1)
                    app.db_local_all.project_dir = app.CallingApp.ProjectDir;
                    app.db_local_all.matlab_dir = fullfile(matlabroot, 'bin', 'matlab');
                    app.db_local_all.device_name = device.Device;
                    app.db_local_all.device_id = device.DeviceID;
                else
                    for dev=1:length(device.Device)
                        db_local_temp = struct('project_dir', '', 'matlab_dir', '', 'device_name', {}, 'device_id', {}); 
                        db_local_temp(1).project_dir = app.CallingApp.ProjectDir;
                        db_local_temp(1).matlab_dir = fullfile(matlabroot, 'bin', 'matlab');
                        db_local_temp(1).device_name = device.Device{dev};
                        db_local_temp(1).device_id = device.DeviceID(dev);
                        app.db_local_all = cat(1, app.db_local_all, db_local_temp);
                    end
                end
            end
            app.db_local_process = struct('project_dir', '', 'matlab_dir', '', 'device_name', {}, 'device_id', {}); 
            app.db_local_remain = struct('project_dir', '', 'matlab_dir', '', 'device_name', {}, 'device_id', {}); 
            
            % check remote info in cfg.mat
            if(exist(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'), 'file') && ~isempty(whos('-FILE', fullfile(app.CallingApp.ProjectDir, 'cfg.mat'), 'db_remote')))
                temp = load(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'), 'db_remote');
                app.db_remote_all = temp.db_remote;
            else
                app.db_remote_all = struct('ip', '', 'user_name', '', 'passwd', '', 'project_dir', '', 'matlab_dir', '', ...
                                               'device_name', {}, 'device_id', {});
            end
            app.db_remote_remain = struct('ip', '', 'user_name', '', 'passwd', '', 'project_dir', '', 'matlab_dir', '', ...
                                          'device_name', {}, 'device_id', {});
            app.db_remote_process = struct('ip', '', 'user_name', '', 'passwd', '', 'project_dir', '', 'matlab_dir', '', ...
                                           'device_name', {}, 'device_id', {});
                                   
            % init subject db
            app.db_subject_all = app.CallingApp.db_subject_data;  
            app.db_subject_process = struct('subject_status', 1, 'subject_name', '', 'subject_mri', '', 'subject_fs', '',...
                                             'subject_scs', struct('NAS', [], 'LPA', [], 'RPA', []), ...
                                             'meg_name', {}, 'meg_file', {}, 'meg_status', {},...
                                             'channel_mat', {}, 'results', {}); 
            app.db_subject_remain = struct('subject_status', 1, 'subject_name', '', 'subject_mri', '', 'subject_fs', '',...
                                           'subject_scs', struct('NAS', [], 'LPA', [], 'RPA', []), ...
                                           'meg_name', {}, 'meg_file', {}, 'meg_status', {},...
                                           'channel_mat', {}, 'results', {}); 
                                       
            % init process info
            app.db_process = struct('is_remote', 0, 'process_info', []);
            
            % init remote process
            app.ButtonUsingNetwork.Value = 0;
            ButtonUsingNetworkValueChanged(app);
            
            close(wait_bar);
        end

        % Button pushed function: ButtonParam
        function ButtonParamPushed(app, event)
            app.GridLayout.Visible = 0;
            app.DialogProcessAll = DialogSetParam(app);  
        end

        % Button pushed function: ButtonAddSubject
        function ButtonAddSubjectPushed(app, event)
            select_value = app.ListBoxSubjectRemain.Value;
            if(~isempty(select_value))
                UpdateSubjectList(app);
                select_index = cellfun(@(x)find(contains(app.ListBoxSubjectRemain.Items, x)), select_value);
                app.db_subject_process = cat(1, app.db_subject_process, app.db_subject_remain(select_index));
                UpdateSubjectList(app);
            end
        end

        % Button pushed function: ButtonRemoveSubject
        function ButtonRemoveSubjectPushed(app, event)
            select_value = app.ListBoxSubjectProcess.Value;
            if(~isempty(select_value))
                select_index = cellfun(@(x)find(contains(app.ListBoxSubjectProcess.Items, x)), select_value);
                app.db_subject_process(select_index) = [];
                UpdateSubjectList(app);
            end    
        end

        % Value changed function: ButtonUsingNetwork
        function ButtonUsingNetworkValueChanged(app, event)
            value = app.ButtonUsingNetwork.Value;
            if(value)
                app.GroupProcessIsNet = 1;
                app.PanelServerRemain.Title = 'Servers';
                app.PanelServerProcess.Title = 'Servers to Process';
                app.ButtonUsingNetwork.Text = 'Using Network';
                app.ButtonAddServer.Enable = 1;
                app.ButtonTestServer.Enable = 1;
                app.ButtonUsingNetwork.BackgroundColor = [0.64,0.99,0.51];
                ButtonTestServerPushed(app);
            else
                app.GroupProcessIsNet = 0;
                app.PanelServerRemain.Title = 'Local';
                app.PanelServerProcess.Title = 'Local to Process';   
                app.ButtonUsingNetwork.Text = 'Using Local'; 
                app.ButtonAddServer.Enable = 0;
                app.ButtonTestServer.Enable = 0;   
                app.ButtonUsingNetwork.BackgroundColor = [0.80,0.80,0.80];    
                UpdateDeviceListLocal(app);
            end
        end

        % Button pushed function: ButtonAddServer
        function ButtonAddServerPushed(app, event)
            if(~app.Process_isOK)
                app.Process_isOK = 0;
                app.GridLayout.Visible = 0;
                DialogEnterRemote(app);
            end
        end

        % Button pushed function: ButtonTestServer
        function ButtonTestServerPushed(app, event)
            % get all db_remote and unique
            remote_info = cellfun(@(x)cat(2, x.ip, '?', x.user_name, '?', x.passwd, '?', fileparts(x.project_dir), '?', x.matlab_dir), ...
                                          num2cell(app.db_remote_all), 'UniformOutput', false);
            remote_info = unique(remote_info);
            remote_info = cellfun(@(x)strsplit(x, '?'), remote_info, 'UniformOutput', false);
            db_remote_test = [];
            for i=1:length(remote_info)
                % test db_remote
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Checking remote computer','Message',['IP: ',num2str(i),'/',num2str(length(remote_info)), '; Status: Checking path'],'value',i/length(remote_info));
                error = TestRemote(remote_info{i}{5}, remote_info{i}{4}, remote_info{i}{2}, remote_info{i}{3}, remote_info{i}{1}, app.sshpass_cmd);
                close(wait_bar);
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Checking remote computer','Message',['IP',num2str(i),'/',num2str(length(remote_info)), '; Status: Checking devices'],'value',i/length(remote_info));
                device = GetDevice(remote_info{i}{1}, remote_info{i}{2}, remote_info{i}{3}, app.sshpass_cmd);
                close(wait_bar);
                if(isempty(error) && ~isempty(device))
                    % get available db_remote
                    db_remote_temp = [];
                    for ip=1:length(device.Device)
                        db_remote_temp = struct('ip', '', 'user_name', '', 'passwd', '', 'project_dir', '', 'matlab_dir', '', ...
                                                'device_name', {}, 'device_id', {}); 
                        db_remote_temp(1).ip = remote_info{i}{1};
                        db_remote_temp(1).user_name = remote_info{i}{2};
                        db_remote_temp(1).passwd = remote_info{i}{3};
                        db_remote_temp(1).project_dir = fullfile(remote_info{i}{4}, ['Device', num2str(device.DeviceID(ip))]);
                        db_remote_temp(1).matlab_dir = remote_info{i}{5};
                        db_remote_temp(1).device_name = device.Device{ip};
                        db_remote_temp(1).device_id = device.DeviceID(ip);
                        db_remote_test = cat(1, db_remote_test, db_remote_temp);
                    end
                end
            end
            app.db_remote_all = db_remote_test;
            % updata
            UpdateIPListRemote(app);
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            select_value = app.ListBoxServerRemain.Value;
            if(~isempty(select_value))
                select_index = cellfun(@(x)find(contains(app.ListBoxServerRemain.Items, x)), select_value);
                if(app.GroupProcessIsNet)
                    app.db_remote_process = cat(1, app.db_remote_process, app.db_remote_remain(select_index));
                    UpdateIPListRemote(app);
                else
                    app.db_local_process = cat(1, app.db_local_process, app.db_local_remain(select_index));
                    UpdateDeviceListLocal(app);
                end
            end
        end

        % Button pushed function: ButtonRemove
        function ButtonRemovePushed(app, event)
            select_value = app.ListBoxServerProcess.Value;
            if(~isempty(select_value))
                select_index = cellfun(@(x)find(contains(app.ListBoxServerProcess.Items, x)), select_value);
                if(app.GroupProcessIsNet)
                    app.db_remote_process(select_index) = [];
                    UpdateIPListRemote(app);
                else
                    app.db_local_process(select_index) = [];
                    UpdateDeviceListLocal(app);
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % generate process struct
            is_done = GenerateProcessStruct(app);
            if(is_done == 0)
                uialert(app.UIFigure, 'Input error', 'Input error');
            end
            % generate bash cmd and input data of remote process
            GenerateShellCMD(app);
            % save db_remote_all and db_process to cfg.mat
            SaveCfgMat(app);
            % return to main GUI
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.db_process = app.db_process;
            app.CallingApp.db_remote = app.db_remote_all;
            Output = app.CallingApp;
            delete(app);
            GroupProcessReturn(Output);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Interupt = 1;
            app.CallingApp.GridLayout.Visible = 1;
            delete(app);
        end

        % Value changed function: ListBoxSubjectRemain
        function ListBoxSubjectRemainValueChanged(app, event)
            % show meg file
            if(~isempty(app.ListBoxSubjectRemain.Value))
                select_index = cellfun(@(x)find(contains(app.ListBoxSubjectRemain.Items, x)), app.ListBoxSubjectRemain.Value);
                app.ListBoxSubjectMEG.Items = app.db_subject_remain(select_index).meg_name;
            else
                app.ListBoxSubjectMEG.Items = {};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PanelSubject = uipanel(app.GridLayout);
            app.GridLayoutSubject = uigridlayout(app.PanelSubject);
            app.PanelSubjectRemain = uipanel(app.GridLayoutSubject);
            app.PanelSubjectRemain.Title = 'Subjects';
            app.GridLayoutSubjectRemain = uigridlayout(app.PanelSubjectRemain);
            app.ListBoxSubjectRemain = uilistbox(app.GridLayoutSubjectRemain);
            app.ListBoxSubjectRemain.Items = {'None'};
            app.ListBoxSubjectRemain.Multiselect = 'on';
            app.ListBoxSubjectRemain.ValueChangedFcn = createCallbackFcn(app, @ListBoxSubjectRemainValueChanged, true);
            app.ListBoxSubjectRemain.Tooltip = {''};
            app.ListBoxSubjectRemain.Value = {'None'};
            app.PanelSubjectMEG = uipanel(app.GridLayoutSubject);
            app.PanelSubjectMEG.Title = 'MEG Files of Subject';
            app.GridLayoutSubjectMEG = uigridlayout(app.PanelSubjectMEG);
            app.ListBoxSubjectMEG = uilistbox(app.GridLayoutSubjectMEG);
            app.ListBoxSubjectMEG.Items = {'None'};
            app.ListBoxSubjectMEG.Multiselect = 'on';
            app.ListBoxSubjectMEG.Value = {'None'};
            app.PanelSubjectProcess = uipanel(app.GridLayoutSubject);
            app.PanelSubjectProcess.Title = 'Subjects to Process';
            app.GridLayoutSubjectProcess = uigridlayout(app.PanelSubjectProcess);
            app.ListBoxSubjectProcess = uilistbox(app.GridLayoutSubjectProcess);
            app.ListBoxSubjectProcess.Items = {'None'};
            app.ListBoxSubjectProcess.Multiselect = 'on';
            app.ListBoxSubjectProcess.Value = {'None'};
            app.ButtonAddSubject = uibutton(app.GridLayoutSubject, 'push');
            app.ButtonAddSubject.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddSubjectPushed, true);
            app.ButtonAddSubject.Text = '-->';
            app.ButtonRemoveSubject = uibutton(app.GridLayoutSubject, 'push');
            app.ButtonRemoveSubject.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemoveSubjectPushed, true);
            app.ButtonRemoveSubject.Text = '<--';
            app.PanelParam = uipanel(app.GridLayout);
            app.GridLayoutParam = uigridlayout(app.PanelParam);
            app.ButtonParam = uibutton(app.GridLayoutParam, 'push');
            app.ButtonParam.ButtonPushedFcn = createCallbackFcn(app, @ButtonParamPushed, true);
            app.ButtonParam.Text = 'Set Parameters of Process';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PanelNetwork = uipanel(app.GridLayout);
            app.GridLayoutNetwork = uigridlayout(app.PanelNetwork);
            app.ButtonAddServer = uibutton(app.GridLayoutNetwork, 'push');
            app.ButtonAddServer.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddServerPushed, true);
            app.ButtonAddServer.Text = 'Add Server';
            app.ButtonTestServer = uibutton(app.GridLayoutNetwork, 'push');
            app.ButtonTestServer.ButtonPushedFcn = createCallbackFcn(app, @ButtonTestServerPushed, true);
            app.ButtonTestServer.Text = 'Test Server';
            app.PanelServerRemain = uipanel(app.GridLayoutNetwork);
            app.PanelServerRemain.Title = 'Servers';
            app.GridLayoutServerRemain = uigridlayout(app.PanelServerRemain);
            app.ListBoxServerRemain = uilistbox(app.GridLayoutServerRemain);
            app.ListBoxServerRemain.Items = {'None'};
            app.ListBoxServerRemain.Multiselect = 'on';
            app.ListBoxServerRemain.Value = {'None'};
            app.PanelServerProcess = uipanel(app.GridLayoutNetwork);
            app.PanelServerProcess.Title = 'Servers to Process';
            app.GridLayoutServerProcess = uigridlayout(app.PanelServerProcess);
            app.ListBoxServerProcess = uilistbox(app.GridLayoutServerProcess);
            app.ListBoxServerProcess.Items = {'None'};
            app.ListBoxServerProcess.Multiselect = 'on';
            app.ListBoxServerProcess.Value = {'None'};
            app.ButtonRemove = uibutton(app.GridLayoutNetwork, 'push');
            app.ButtonRemove.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemovePushed, true);
            app.ButtonRemove.Text = 'Del';
            app.ButtonAdd = uibutton(app.GridLayoutNetwork, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Text = 'Add';
            app.PanelisNetwork = uipanel(app.GridLayout);
            app.GridLayoutisNetwork = uigridlayout(app.PanelisNetwork);
            app.ButtonUsingNetwork = uibutton(app.GridLayoutisNetwork, 'state');
            app.ButtonUsingNetwork.ValueChangedFcn = createCallbackFcn(app, @ButtonUsingNetworkValueChanged, true);
            app.ButtonUsingNetwork.Text = 'Using Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogGroupProcess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end