classdef DialogLoadProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        OKButton                       matlab.ui.control.Button
        ProjectPanel                   matlab.ui.container.Panel
        GridLayout_Project             matlab.ui.container.GridLayout
        StudyFileFilterEditField       matlab.ui.control.EditField
        StudyFileFilterEditFieldLabel  matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        StudyPanel                     matlab.ui.container.Panel
        GridLayout_Study               matlab.ui.container.GridLayout
        AddButton_Study                matlab.ui.control.Button
        DeleteButton_Study             matlab.ui.control.Button
        ListBox_Study                  matlab.ui.control.ListBox
        MEGFilesLabel                  matlab.ui.control.Label
        SujectPanel                    matlab.ui.container.Panel
        GridLayout_Subject             matlab.ui.container.GridLayout
        DeleteButton_Subject           matlab.ui.control.Button
        AddButton_Subject              matlab.ui.control.Button
        ListBox_Subject                matlab.ui.control.ListBox
        SubjectsLabel_Subject          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        DataBaseInformationButton      matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % Description
        FileFilter;
        Study;
        Idx;
        StudyState;    %[study,subject,protocol]
        StudySelected;
        
        DialogProcessAll;
        
        State
        
        subject_name_show
        study_name_show
        
        select_file_index; %1: Project; 2: MRI; 3: MEG
        confirm_index; %1: OK; 2: Only Confirm
    end
    
    properties (Access = public)
        SelectPath;
        
        Interupt;
        Process_isOK=0;
        BrainstormDbDir
        ProjectDir
        db_subject_data;
        db_remote;
        db_process;
    end
    
    methods (Access = private)
        % Check data and show data in GUI
        function set_db_show(app)
            app.subject_name_show = cell(length(app.db_subject_data), 1);
            app.study_name_show = cell(length(app.db_subject_data), 1);
            for i=1:length(app.db_subject_data)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Loading data','Message',['Loading data: ',num2str(i),'/',num2str(length(app.db_subject_data))],'value',i/length(app.db_subject_data));
                % Get subject name, anatomy type and file status
                if(~isempty(app.db_subject_data(i).subject_fs))
                    switch app.db_subject_data(i).subject_status
                        case 0
                            app.subject_name_show{i} = cat(2, 'FS: ', app.db_subject_data(i).subject_name);
                        case 1
                            app.subject_name_show{i} = cat(2, '[NEW]FS: ', app.db_subject_data(i).subject_name);
                        case 2
                            app.subject_name_show{i} = cat(2, '[!!DELETE!!]FS: ', app.db_subject_data(i).subject_name);
                        case 3
                            app.subject_name_show{i} = cat(2, '[NEW & DELETE]FS: ', app.db_subject_data(i).subject_name);
                    end
                elseif(~isempty(app.db_subject_data(i).subject_mri))
                    switch app.db_subject_data(i).subject_status
                        case 0
                            app.subject_name_show{i} = cat(2, 'MRI: ', app.db_subject_data(i).subject_name);
                        case 1
                            app.subject_name_show{i} = cat(2, '[NEW]MRI: ', app.db_subject_data(i).subject_name);
                        case 2
                            app.subject_name_show{i} = cat(2, '[!!DELETE!!]MRI: ', app.db_subject_data(i).subject_name);
                        case 3
                            app.subject_name_show{i} = cat(2, '[NEW & DELETE]MRI: ', app.db_subject_data(i).subject_name);
                    end
                else
                    app.subject_name_show{i} = {};
                end
                % Get study name
                if(~isempty(app.db_subject_data(i).meg_file))
                    for fif=1:length(app.db_subject_data(i).meg_file)
                        switch app.db_subject_data(i).meg_status(fif)
                            case 0
                                app.study_name_show{i} = cat(1, app.study_name_show{i}, app.db_subject_data(i).meg_name(fif));
                            case 1
                                app.study_name_show{i} = cat(1, app.study_name_show{i}, {cat(2, '[NEW]', app.db_subject_data(i).meg_name{fif})});
                            case 2
                                app.study_name_show{i} = cat(1, app.study_name_show{i}, {cat(2, '[!!DELETE!!]', app.db_subject_data(i).meg_name{fif})});
                            case 3
                                app.study_name_show{i} = cat(1, app.study_name_show{i}, {cat(2, '[NEW & DELETE]', app.db_subject_data(i).meg_name{fif})});
                        end
                    end
                else
                    app.study_name_show{i} = {};
                end
                close(wait_bar);
            end
            app.ListBox_Subject.Items = app.subject_name_show;app.ListBox_Subject.ItemsData = 1:length(app.ListBox_Subject.Items);
            if(isempty(app.ListBox_Subject.Value))
                app.ListBox_Subject.Value = {};
                app.ListBox_Study.Items = {};app.ListBox_Study.ItemsData = [];app.ListBox_Study.Value = {};
            else
                app.ListBox_Study.Items = app.study_name_show{app.ListBox_Subject.Value};
                app.ListBox_Study.ItemsData = 1:length(app.ListBox_Study.Items);
                if(isempty(app.ListBox_Study.Value) || all(~ismember(app.ListBox_Study.ItemsData, app.ListBox_Study.Value)))
                    app.ListBox_Study.Value = {};
                end
            end
        end
        
        % import freesurfer folder
        function iSubject = imort_fs_mri(~, temp_data, nVertices)
            % Step1: make protocol
            temp = bst_get('Protocol', temp_data.subject_name);
            if(isempty(temp))
                % if not exist, then creat
                sProtocol = db_template('protocolinfo');
                sProtocol.Comment = temp_data.subject_name;
                sProtocol.SUBJECTS = fullfile(bst_get('BrainstormDbDir'), temp_data.subject_name, 'anat');
                sProtocol.STUDIES = fullfile(bst_get('BrainstormDbDir'), temp_data.subject_name, 'data');
                db_edit_protocol('create', sProtocol);
                gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', temp_data.subject_name));
            else
                % load protocol
                gui_brainstorm('SetCurrentProtocol', temp);
            end
            
            % Step2: make subject
            if(isempty(bst_get('Subject', temp_data.subject_name, 1)))
                % if not exist, then creat
                [~, iSubject] = db_add_subject(temp_data.subject_name);
                db_reload_subjects(iSubject)
                [~, iSubject] = bst_get('Subject', temp_data.subject_name, 1);
            else
                % get subject id
                [~, iSubject] = bst_get('Subject', temp_data.subject_name, 1);
                db_reload_subjects(iSubject)
                [~, iSubject] = bst_get('Subject', temp_data.subject_name, 1);
            end
            
            % Step3: get NAS, LPA and RPA in mri corrdinate
            [mri, ~] = in_mri(temp_data.subject_mri, 'ALL', 0);
            sFid.NAS = cs_convert(mri, 'voxel', 'mri', temp_data.subject_scs.NAS) .* 1000;
            sFid.LPA = cs_convert(mri, 'voxel', 'mri', temp_data.subject_scs.LPA) .* 1000;
            sFid.RPA = cs_convert(mri, 'voxel', 'mri', temp_data.subject_scs.RPA) .* 1000;
            % normalize to mni
            tpm_file = bst_get('SpmTpmAtlas');trans_min = mri_normalize_maff(mri, tpm_file);
            mri.NCS.R = trans_min(1:3,1:3);mri.NCS.T = trans_min(1:3,4);
            
            % Step4: get AC, PC. IH in mri corrdinate
            AC  = [0,   3,  -4] ./ 1000;PC  = [0, -25,  -2] ./ 1000;IH  = [0, -10,  60] ./ 1000;Orig= [0,   0,   0]; 
            sFid.AC     = cs_convert(mri, 'mni', 'mri', AC) .* 1000;
            sFid.PC     = cs_convert(mri, 'mni', 'mri', PC) .* 1000;
            sFid.IH     = cs_convert(mri, 'mni', 'mri', IH) .* 1000;
            sFid.Origin = cs_convert(mri, 'mni', 'mri', Orig) .* 1000;
            
            % Step5: import fs or mri
            if(~isempty(temp_data.subject_fs))
                % import fs
                errorMsg = import_anatomy_fs(iSubject, temp_data.subject_fs, nVertices, 0, sFid, 1, 1, 0);
            else
                % import mri
                % make sure no "MRI" exist
                sSubject = bst_get('Subject', iSubject);
                if(any(contains({sSubject.Anatomy.Comment}, 'MRI')))
                    % detele file
                    file_delete(file_fullpath(sSubject.Anatomy(contains({sSubject.Anatomy.Comment}, 'MRI')).FileName), 1);
                end
                db_reload_subjects(iSubject);
                % import MRI
                import_mri(1, temp_data.subject_mri, 'ALL', 0, 1, 'MRI');
                % add fidicual
                figure_mri('SetSubjectFiducials', iSubject, sFid.NAS, sFid.LPA, sFid.RPA, sFid.AC, sFid.PC, sFid.IH);
                % get scaple surface
                tess_isohead(iSubject, 10000, 0, 2);
            end
        end
                
        % uodate anatomy and meg files
        function update_bst(app)
            for i=1:length(app.db_subject_data)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                    ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data))],'value',i/length(app.db_subject_data));
                temp_data = app.db_subject_data(i);
                % Import anatomy
                if(temp_data.subject_status == 1)
                    % import anatomy data 
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Import anatomy data'], 'value',i/length(app.db_subject_data));
                    iSubject = imort_fs_mri(app, temp_data, 15000);
                    % get head shape for refining meg sensor
                    temp = bst_get('Subject', iSubject);
                    SurfaceMat = bst_memory('LoadSurface', temp.Surface(temp.iScalp).FileName);
                elseif(temp_data.subject_status == 0)
                    % only load anatomy data 
                    gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', temp_data.subject_name));
                    temp = bst_get('ProtocolSubjects');temp=temp.Subject;iSubject = find(contains({temp.Name}, temp_data.subject_name));
                    % get head shape for refining meg sensor
                    temp = bst_get('Subject', iSubject);
                    SurfaceMat = bst_memory('LoadSurface', temp.Surface(temp.iScalp).FileName);
                elseif(temp_data.subject_status == 2)
                    % delete exist data
                    gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', temp_data.subject_name));
                    db_delete_protocol(1, 1);
                end
                % Import MEG
                if(((temp_data.subject_status == 0) || temp_data.subject_status == 1) && ~isempty(temp_data.meg_file))
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Import MEG data'], 'value',i/length(app.db_subject_data));
                    temp_meg = temp_data.meg_file;
                    % get channel mat and refine sensor locationns
                    temp = db_template('ImportOptions');temp.EventsMode = 'no';
                    for fif=1:length(temp_data.meg_file)
                        if(temp_data.meg_status(fif) == 1)
                            [sFile, ChannelMat] = in_fopen_fif(temp_data.meg_file{fif}, temp);
                            [ChannelMat, ~, ~] = AlignChannel(ChannelMat, SurfaceMat);
                            ChannelMat.ChannelFlag = sFile.channelflag;
                            app.db_subject_data(i).channel_mat(fif) = ChannelMat;
                        end
                    end
                end
                close(wait_bar)
            end
            
        end
        
        % creat bem surface
        function creat_bem_surf(app)
            for i=1:length(app.db_subject_data)
                if(app.db_subject_data(i).subject_status == 1)
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data))], 'value',i/length(app.db_subject_data));
                    bem_surf_dir = fullfile(app.ProjectDir, app.db_subject_data(i).subject_name, ... 
                                'FreeSurfer', app.db_subject_data(i).subject_name, 'bem');
                    if(~exist(bem_surf_dir, 'dir'))
                       mkdir(bem_surf_dir) 
                    end
                    % get iSubject
                    gui_brainstorm('SetCurrentProtocol', bst_get('Protocol', app.db_subject_data(i).subject_name));
                    surf_name = bst_get('ProtocolSubjects');surf_name=surf_name.Subject;
                    iSubject = find(contains({surf_name.Name}, app.db_subject_data(i).subject_name));
                    
                    
                    % segment brains using ft_volumesegment and name as "tissues"
                    % step1: make sure no "tissues" exist
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Segment MRI'], 'value',i/length(app.db_subject_data));
                    sSubject = bst_get('Subject', iSubject);
                    if(any(cellfun(@(x)strcmp(x, 'tissues'), {sSubject.Anatomy.Comment})))
                        % detele file
                        file_delete(file_fullpath(sSubject.Anatomy(cellfun(@(x)strcmp(x, 'tissues'), {sSubject.Anatomy.Comment})).FileName), 1);
                    end
                    if(any(cellfun(@(x)strcmp(x, 'tissues_spm'), {sSubject.Anatomy.Comment})))
                        % detele file
                        file_delete(file_fullpath(sSubject.Anatomy(cellfun(@(x)strcmp(x, 'tissues_spm'), {sSubject.Anatomy.Comment})).FileName), 1);
                    end
                    if(any(cellfun(@(x)strcmp(x, 'tissues_ft'), {sSubject.Anatomy.Comment})))
                        % detele file
                        file_delete(file_fullpath(sSubject.Anatomy(cellfun(@(x)strcmp(x, 'tissues_ft'), {sSubject.Anatomy.Comment})).FileName), 1);
                    end
                    db_reload_subjects(iSubject); 
                    % step2: ft_segment
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Segment MRI (ft)'], 'value',i/length(app.db_subject_data));
                    sSubject = bst_get('Subject', iSubject);
                    OPTIONS = process_ft_volumesegment('GetDefaultOptions');OPTIONS.isSaveTess = 0;
                    iMris = find(contains({sSubject.Anatomy.Comment}, 'MRI'));
                    process_ft_volumesegment('Compute', iSubject, iMris, OPTIONS);
                    % rename ft_segment
                    sSubject = bst_get('Subject', iSubject);
                    tissue_path = file_fullpath(sSubject.Anatomy(cellfun(@(x)strcmp(x, 'tissues'), {sSubject.Anatomy.Comment})).FileName);
                    tissue_mat = load(tissue_path);tissue_mat.Comment = 'tissues_ft';
                    save(tissue_path, '-struct', 'tissue_mat');
                    db_reload_subjects(iSubject);
                    % step3: spm_segment
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Segment MRI (SPM)'], 'value',i/length(app.db_subject_data));
                    sSubject = bst_get('Subject', iSubject);
                    t1_mri = sSubject.Anatomy(contains({sSubject.Anatomy.Comment}, 'MRI'));
                    [~, ~] = bst_normalize_mni(t1_mri.FileName, 'segment');
                    % rename spm_segment
                    sSubject = bst_get('Subject', iSubject);
                    tissue_path = file_fullpath(sSubject.Anatomy(cellfun(@(x)strcmp(x, 'tissues_segment'), {sSubject.Anatomy.Comment})).FileName);
                    tissue_mat = load(tissue_path);tissue_mat.Comment = 'tissues_spm';
                    save(tissue_path, '-struct', 'tissue_mat');
                    db_reload_subjects(iSubject);
                    % step4: modify segment
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Segment MRI (Modify)'], 'value',i/length(app.db_subject_data));
                    % get spm and ft segment
                    sSubject = bst_get('Subject', iSubject);
                    segment_ft = load(file_fullpath(sSubject.Anatomy(contains({sSubject.Anatomy.Comment}, 'tissues_ft')).FileName)); 
                    segment_spm = load(file_fullpath(sSubject.Anatomy(contains({sSubject.Anatomy.Comment}, 'tissues_spm')).FileName)); 
                    % get head mask
                    smri = bst_memory('LoadMri', sSubject.Anatomy(sSubject.iAnatomy).FileName);
                    bgLevel = smri.Histogram.bgLevel;
                    headmask = (smri.Cube(:,:,:,1) > bgLevel);
                    % Closing all the faces of the cube
                    headmask(1,:,:)   = 0*headmask(1,:,:);headmask(end,:,:) = 0*headmask(1,:,:);
                    headmask(:,1,:)   = 0*headmask(:,1,:);headmask(:,end,:) = 0*headmask(:,1,:);
                    headmask(:,:,1)   = 0*headmask(:,:,1);headmask(:,:,end) = 0*headmask(:,:,1);
                    headmask = (mri_fillholes(headmask, 1) & mri_fillholes(headmask, 2) & mri_fillholes(headmask, 3));
                    % modify
                    segment_modified = ModifiedSegment(segment_spm, segment_ft, headmask);
                    % save
                    tissue_path = fullfile(fileparts(file_fullpath(sSubject.Anatomy(contains({sSubject.Anatomy.Comment}, 'tissues_ft')).FileName)), ...
                                           'subjectimage_MRI_tissues_md.mat');
                    save(tissue_path, '-struct', 'segment_modified');
                    db_reload_subjects(iSubject);
                    
                    % make inter/outer skull and outer scaple surface
                    % make sure no inter/outer skull and outer scaple surface exist
                    wait_bar = uiprogressdlg(app.UIFigure,'Title','Updating bst protocol','Message',...
                        ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Generate bem surface'], 'value',i/length(app.db_subject_data));
                    surf_name = {'outer_skin', 'inner_skull', 'outer_skull', 'brain'};
                    for surf=1:length(surf_name)
                        sSubject = bst_get('Subject', iSubject);
                        if(any(contains({sSubject.Surface.Comment}, surf_name{surf})))
                            % detele file
                            file_delete(file_fullpath(sSubject.Surface(contains({sSubject.Surface.Comment}, surf_name{surf})).FileName), 1);
                            % delete db
                            sSubject.Surface(contains({sSubject.Surface.Comment}, surf_name{surf})) = [];
                            bst_set('Subject', iSubject, sSubject);
                            panel_protocols('UpdateNode', 'Subject', iSubject);
                            % Save database
                            db_save(1);
                        end
                    end
                    db_reload_subjects(iSubject);
                    % make surface
                    sSubject = bst_get('Subject', iSubject);
                    TissueFile = sSubject.Anatomy(contains({sSubject.Anatomy.Comment}, 'tissues_md')).FileName;
                    TissueLabels = {'Scalp','Skull','CSF','Gray'};
                    nVertices=10242;erodeFactor = 0;fillFactor = 2;
                    % Add Path
                    PrivateFigure3dPath = which('Main.mlapp');addpath(genpath([fileparts(fileparts(PrivateFigure3dPath)),'/ExternalFun']));
                    [surf_mat, ~] = MeshLayer(TissueFile, TissueLabels, nVertices, erodeFactor, fillFactor);
                    % Remove Path
                    PrivateFigure3dPath = which('Main.mlapp');rmpath(genpath([fileparts(fileparts(PrivateFigure3dPath)),'/ExternalFun']));
                    % save surf file
                    sSubject = bst_get('Subject', iSubject);smri = bst_memory('LoadMri', sSubject.Anatomy(sSubject.iAnatomy).FileName);
                    for surf=1:length(surf_mat)
                        tess_mat = load(surf_mat{surf});
                        tess_mat.Vertices = cs_convert(smri, 'scs', 'mri', tess_mat.Vertices) - (size(smri.Cube)/2 + [0 1 0]) ./ smri.Voxsize / 1000;
                        out_tess_fs(tess_mat, fullfile(bem_surf_dir, [tess_mat.Comment, '.surf']))
                    end
                    close(wait_bar);
                    
                    % generate MNE trans (head => RAS_Surface)
                    % make dir
                    trans_dir = fullfile(bem_surf_dir, 'trans');
                    if(~exist(trans_dir, 'dir'))
                       mkdir(trans_dir) 
                    end
                    % generate trans
                    sSubject = bst_get('Subject', iSubject);
                    smri = bst_memory('LoadMri', sSubject.Anatomy(sSubject.iAnatomy).FileName);
                    for fif=1:length(app.db_subject_data(i).channel_mat)
                        ExportHead2RAS_trans(smri, app.db_subject_data(i).channel_mat(fif), fullfile(trans_dir, [app.db_subject_data(i).meg_name{fif}, '-trans.fif']));
                    end
                end
            end
        end
        
        % Check import anatomy path
        function check_anatomy_path(app, anatomy_path)
            temp_db = struct('subject_status', 1, 'subject_name', '', 'subject_mri', '', 'subject_fs', '',...
                             'subject_scs', struct('NAS', [], 'LPA', [], 'RPA', []), ...
                             'meg_name', {}, 'meg_file', {}, 'meg_status', {},...
                             'channel_mat', {}, 'results', {}); 
            if(exist(anatomy_path, 'dir'))
                % if path is fs
                temp = dir(anatomy_path);temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                if(any(contains('mri', {temp.name})) && any(contains('surf', {temp.name})))
                    % surf and mri exist
                    temp_mri = dir(fullfile(anatomy_path, 'mri'));temp_mri = temp_mri(~cellfun(@(x)strcmp(x(1), '.'), {temp_mri.name}));
                    temp_surf = dir(fullfile(anatomy_path, 'surf'));temp_surf = temp_surf(~cellfun(@(x)strcmp(x(1), '.'), {temp_surf.name}));
                    if(any(contains('T1.mgz', {temp_mri.name})) && any(contains('rh.pial', {temp_surf.name})))
                        temp_mri = temp_mri(contains({temp_mri.name}, 'T1.mgz'));temp_mri = fullfile(temp_mri(1).folder, temp_mri(1).name);
                        % make sure anatomy_path not in db
                        if(isempty({app.db_subject_data.subject_fs}) || ~any(contains({app.db_subject_data.subject_fs}, anatomy_path)))
                            temp_db(1).subject_status = 1;
                            temp_db(1).subject_mri = temp_mri;
                            temp_db(1).subject_fs = anatomy_path;
                            app.db_subject_data = cat(1, app.db_subject_data, temp_db);
                        end
                    end
                end
            elseif(exist(anatomy_path, 'file'))
                % if path is mri
                if(contains(anatomy_path, '.nii.gz') || contains(anatomy_path, '.nii') || ...
                   contains(anatomy_path, '.mgz') || contains(anatomy_path, '.img'))
                    % make sure anatomy_path not in db
                    if(isempty({app.db_subject_data.subject_mri}) || ~any(contains({app.db_subject_data.subject_mri}, anatomy_path)))
                        temp_db(1).subject_status = 1;
                        temp_db(1).subject_mri = anatomy_path;
                        temp_db(1).subject_fs = [];
                        app.db_subject_data = cat(1, app.db_subject_data, temp_db);
                    end
                end
            end
        end
        
        % update subject file name and check repeat
        function update_subject_name(app)
            % get subject name
            subject_name = cell(length(app.db_subject_data), 1);
            for i=1:length(app.db_subject_data)
                if(~isempty(app.db_subject_data(i).subject_fs))
                    temp = strrep(app.db_subject_data(i).subject_fs, fileparts(app.db_subject_data(i).subject_fs), '');
                    subject_name{i} = temp(2:end);
                elseif(~isempty(app.db_subject_data(i).subject_mri))
                    temp = strrep(app.db_subject_data(i).subject_mri, fileparts(app.db_subject_data(i).subject_mri), '');temp = temp(2:end);
                    subject_name{i} = strrep(strrep(strrep(strrep(temp, '.mgz', ''), '.nii.gz', ''), '.nii', ''), '.img', '');
                end
            end
            % keep previous subject name (subject_status != 1)
            for i=1:length(app.db_subject_data)
                if(app.db_subject_data(i).subject_status == 1 || app.db_subject_data(i).subject_status == 3)
                    app.db_subject_data(i).subject_name = subject_name{i};
                end
            end
            % sort according to subject name
            [~, index] = sort({app.db_subject_data.subject_name});
            app.db_subject_data = app.db_subject_data(index);
        end
        
        % check same subject name
        % repeat name: subject_status != 0 -> no change; subject_status == 1 -> change.
        function check_repeat_subject(app)
            subject_name = {app.db_subject_data.subject_name};
            % check repeat subject name and rename
            % exclude exist subject
            index = cat(1, app.db_subject_data.subject_status) == 1 | cat(1, app.db_subject_data.subject_status) == 3;
            repeat_index = cellfun(@(x)find(contains(subject_name, x)), unique(subject_name), 'UniformOutput', false);
            for rep=1:length(repeat_index)
                if(length(repeat_index{rep}) > 1)
                    % add the maximum number index of exist subject.
                    base_num = max(cellfun(@(x)min(str2double(x(end)), strcmp(x(end-1), '_') * 10000), {app.db_subject_data(find(~index)).subject_name}));
                    if(isempty(base_num))
                       base_num = 0; 
                    end
                    % only modified added subject (subject_status = 0)
                    temp = repeat_index{rep}(ismember(repeat_index{rep}, find(index)));
                    subject_name(temp) = cellfun(@(x, y)[x, '_', num2str(y)], ...
                        subject_name(temp), num2cell(base_num + (1:length(temp)))', 'UniformOutput', false);
                end
            end
            % keep previous subject name (subject_status != 1)
            for i=1:length(app.db_subject_data)
                if(app.db_subject_data(i).subject_status == 1)
                    app.db_subject_data(i).subject_name = subject_name{i};
                end
            end
        end
        
        % set fiducials in db 
        function set_db_fiducials(app)
            for i=1:length(app.db_subject_data)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Set fiducials','Message', ...
                    ['Subjects: ',num2str(i),'/',num2str(length(app.db_subject_data))],'value',i/length(app.db_subject_data));
                if(isempty(app.db_subject_data(i).subject_scs))
                    app.db_subject_data(i).subject_scs = set_fiducials(app, app.db_subject_data(i).subject_mri);
                end
                close(wait_bar);
            end
        end
        
        % check import meg_path
        function check_meg_path(app, meg_path)
            % Get subject index
            temp = strsplit(app.ListBox_Subject.Items{app.ListBox_Subject.Value}, ': ');
            subject_index = find(contains({app.db_subject_data.subject_name}, temp(end)));
            % Sort MEG files
            meg_path = sort(meg_path);
            for i=1:length(meg_path)
                if(contains(meg_path{i}, '.fif'))
                    % make sure meg_path not in db
                    if(isempty(app.db_subject_data(subject_index).meg_file) || ~any(contains(app.db_subject_data(subject_index).meg_file, meg_path(i))))
                        app.db_subject_data(subject_index).meg_file = cat(1, app.db_subject_data(subject_index).meg_file, meg_path(i));
                        app.db_subject_data(subject_index).meg_status = cat(1, app.db_subject_data(subject_index).meg_status, 1);
                    end
                end
            end
        end
        
        % update meg file name
        function update_meg_name(app)
            for i=1:length(app.db_subject_data)
                % get meg name
                if(~isempty(app.db_subject_data(i).meg_file))
                    meg_name = cell(length(app.db_subject_data(i).meg_file), 1);
                    for fif=1:length(meg_name)
                        temp = strrep(strrep(app.db_subject_data(i).meg_file{fif}, '.fif', ''), '_tsss', '');
                        temp = strrep(temp, fileparts(temp), '');
                        if(contains(temp, '_tsss'))
                            temp = [temp, '_tsss'];
                        end
                        meg_name{fif} = temp(2:end);
                    end
                    % keep previous meg name (meg_status != 1)
                    for fif=1:length(meg_name)
                        if(app.db_subject_data(i).meg_status(fif) == 1 || app.db_subject_data(i).meg_status(fif) == 3)
                            app.db_subject_data(i).meg_name{fif} = meg_name{fif};
                        end
                    end
                    % sort according to subject name
                    [~, index] = sort(app.db_subject_data(i).meg_name);
                    app.db_subject_data(i).meg_name = app.db_subject_data(i).meg_name(index);
                    app.db_subject_data(i).meg_file = app.db_subject_data(i).meg_file(index);
                    app.db_subject_data(i).meg_status = app.db_subject_data(i).meg_status(index);
                    if(~isempty(app.db_subject_data(i).channel_mat))
                        app.db_subject_data(i).channel_mat = app.db_subject_data(i).channel_mat(index);
                    end
                    if(~isempty(app.db_subject_data(i).results))
                        app.db_subject_data(i).results = app.db_subject_data(i).results(index);
                    end
                end
            end
        end
        
        % check repeat meg name and creat HFO results path (subject_name/meg_name)
        % repeat name: meg_status != 0 -> no change; meg_status == 1 -> change.
        function check_repeat_meg(app)
            for i=1:length(app.db_subject_data)
                meg_name = app.db_subject_data(i).meg_name;
                if(~isempty(meg_name))
                    % check repeat meg name and rename
                    % exclude previous meg
                    index = cat(1, app.db_subject_data(i).meg_status) == 1 | cat(1, app.db_subject_data(i).meg_status) == 3;
                    repeat_index = cellfun(@(x)find(contains(meg_name, x)), unique(meg_name), 'UniformOutput', false);
                    for rep=1:length(repeat_index)
                        if(length(repeat_index{rep}) > 1)
                            % add the maximum number index of exist meg.
                            base_num = max(cellfun(@(x)min(str2double(x(end)), strcmp(x(end-1), '_') * 10000), app.db_subject_data(i).meg_name(find(~index))));
                            if(isempty(base_num))
                               base_num = 0; 
                            end
                            % only modified added subject (subject_status = 0)
                            temp = repeat_index{rep}(ismember(repeat_index{rep}, find(index)));
                            meg_name(temp) = cellfun(@(x, y)[x, '_', num2str(y)], ...
                                meg_name(temp), num2cell(base_num + (1:length(temp))), 'UniformOutput', false);
                        end
                    end
                    % keep previous meg name and result path (meg_status != 1)
                    for fif=1:length(app.db_subject_data(i).meg_status)
                        if(app.db_subject_data(i).meg_status(fif) == 1)
                            app.db_subject_data(i).meg_name{fif} = meg_name{fif};
                            app.db_subject_data(i).results{fif} = fullfile(app.db_subject_data(i).subject_name, 'Results', meg_name{fif});
                        end
                    end
                end
            end
        end
        
        % copy anatomy and MEG files to project dir 
        function copy_file(app)
            for i=1:length(app.db_subject_data)
                % step1: copy fs
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Copying files','Message',...
                    ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Copy fs file'], 'value',i/length(app.db_subject_data));
                if(app.db_subject_data(i).subject_status == 1)
                    fs_dir = fullfile(app.ProjectDir, app.db_subject_data(i).subject_name, ... 
                                'FreeSurfer', app.db_subject_data(i).subject_name);
                    if(~exist(fs_dir, 'dir'))
                       mkdir(fs_dir) 
                    end
                    if(~isempty(app.db_subject_data(i).subject_fs))
                        temp = dir(app.db_subject_data(i).subject_fs);
                        temp = temp(cellfun(@(x)~strcmp(x(1), '.'), {temp.name}));
                        temp = temp(cellfun(@(x)~contains(x, 'bem'), {temp.name}));
                        for file_idx=1:length(temp)
                            copyfile(fullfile(temp(file_idx).folder, temp(file_idx).name), fullfile(fs_dir, temp(file_idx).name));
                            % rename fs dir to relative dir
                            app.db_subject_data(i).subject_fs = strrep(fs_dir, [app.ProjectDir, filesep], '');
                        end
                    end
                end
                
                % step2: copy mri
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Copying files','Message',...
                    ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Copy mri file'], 'value',i/length(app.db_subject_data));
                if(app.db_subject_data(i).subject_status == 1)
                    mri_dir = fullfile(app.ProjectDir, app.db_subject_data(i).subject_name, 'MRI');
                    if(~exist(mri_dir, 'dir'))
                       mkdir(mri_dir) 
                    end
                    temp = {'.mgz', '.nii.gz', '.nii', '.img'};
                    mri_format = temp{find(cellfun(@(x)contains(app.db_subject_data(i).subject_mri, x), temp), 1, 'first')};
                    copyfile(app.db_subject_data(i).subject_mri, fullfile(mri_dir, [app.db_subject_data(i).subject_name, mri_format]));
                    % rename mri path to relative path
                    app.db_subject_data(i).subject_mri = strrep(fullfile(mri_dir, [app.db_subject_data(i).subject_name, mri_format]),...
                                                                [app.ProjectDir, filesep], '');
                    % change anatomy status
                    app.db_subject_data(i).subject_status = 0;
                end
                
                % step3: copy meg
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Copying files','Message',...
                    ['Subject: ',num2str(i),'/',num2str(length(app.db_subject_data)), ' Status: Copy meg file'], 'value',i/length(app.db_subject_data));
                meg_dir = fullfile(app.ProjectDir, app.db_subject_data(i).subject_name, 'MEG');
                if(~exist(meg_dir, 'dir'))
                   mkdir(meg_dir) 
                end
                for fif=1:length(app.db_subject_data(i).meg_file)
                    if(app.db_subject_data(i).meg_status(fif) == 1)
                        copyfile(app.db_subject_data(i).meg_file{fif},...
                                 fullfile(meg_dir, strrep(app.db_subject_data(i).meg_file{fif}, fileparts(app.db_subject_data(i).meg_file{fif}), '')))
                        % rename meg path to relative path
                        temp = fullfile(meg_dir, strrep(app.db_subject_data(i).meg_file{fif}, fileparts(app.db_subject_data(i).meg_file{fif}), ''));
                        app.db_subject_data(i).meg_file{fif} = strrep(temp, [app.ProjectDir, filesep], '');
                        % mkdir results
                        if(~exist(fullfile(app.ProjectDir, app.db_subject_data(i).results{fif}), 'dir'))
                            mkdir(fullfile(app.ProjectDir, app.db_subject_data(i).results{fif}));
                        end
                        % change meg status
                        app.db_subject_data(i).meg_status(fif) = 0;
                    end
                end
                close(wait_bar);
            end
            % step4: copy function
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Copying files','Message','Status: Copy EMHapp functions','Indeterminate','on');
            running_data_path = fullfile(app.ProjectDir, 'RunningData');
            if(~exist(running_data_path, 'dir'))
                mkdir(running_data_path);
            end
            if(~exist(fullfile(running_data_path, 'Logs'), 'dir'))
                mkdir(fullfile(running_data_path, 'Logs'));
            end
            if(exist(fullfile(running_data_path, 'Fun'), 'dir'))
                rmdir(fullfile(running_data_path, 'Fun'), 's');
            end
            copyfile(fileparts(fileparts(which('GroupProcess.m'))), fullfile(running_data_path, 'Fun'));
            close(wait_bar);
        end
        
        % save project cfg file mat
        function save_cfg_file(app)
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Saving files','Message','Status: Save Cfg.mat','Indeterminate','on');
            % step1: modify db_subject_data, only keep subject_status == 0 and meg_status == 0
            temp = app.db_subject_data;
            temp = temp(cat(1, temp.subject_status) == 0);
            for i=1:length(temp)
                temp.meg_name = temp.meg_name(temp.meg_status == 0);
                temp.meg_file = temp.meg_file(temp.meg_status == 0);
                temp.channel_mat = temp.channel_mat(temp.meg_status == 0);
                temp.results = temp.results(temp.meg_status == 0);
                temp.meg_status = temp.meg_status(temp.meg_status == 0);
            end
            app.db_subject_data = temp;
            % step2: load cfg.mat
            if(exist(fullfile(app.ProjectDir, 'cfg.mat'), 'file'))
                save_mat = load(fullfile(app.ProjectDir, 'cfg.mat'));
            end
            % step3: make save struct
            save_mat.db_subject_data = app.db_subject_data;
            save_mat.BrainstormDbDir = strrep(app.BrainstormDbDir, [app.ProjectDir, filesep], '');
            save_mat.ProjectDir = app.ProjectDir;
            % step3: save struct 
            save(fullfile(app.ProjectDir, 'cfg.mat'), '-struct', 'save_mat', '-v6');
            close(wait_bar);
        end
        
        % Load MRI and set fiducials (Using BST function). 
        function SCS = set_fiducials(app, MRI_File)
            global GlobalData
            MRI_FileName = ['MRI', num2str(round(rand(1) * 1000000))];
            mri = db_template('mrimat');
            Temp = in_mri(MRI_File, [], 0);
            mri.Cube = Temp.Cube;mri.Voxsize = Temp.Voxsize;mri.Comment = Temp.Comment;mri.Header = Temp.Header;mri.InitTransf = Temp.InitTransf;
            mri.Histogram = mri_histogram(Temp.Cube);mri.FileName = MRI_FileName;
            GlobalData.Mri = cat(1, GlobalData.Mri, mri);
            %% ===== CREATE FIGURE =====
            iDS = bst_memory('GetDataSetEmpty');
            % Prepare FigureId structure
            FigureId = db_template('FigureId');
            FigureId.Type     = 'MriViewer';
            FigureId.SubType  = '';
            FigureId.Modality = '';
            % Create figureAa
            [hFig, iFig, ~] = bst_figures('CreateFigure', iDS, FigureId, 'AlwaysCreate');
            if isempty(hFig)
                bst_error('Could not create figure', 'View mri', 0);
                return;
            end
            hFig.Position(3:4) = [1120 840];
            hFig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-hFig.Position(3)/2;
            hFig.Position(2) = app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-hFig.Position(4)/2;
            % Set application data
            setappdata(hFig, 'StudyFile',    '');
            setappdata(hFig, 'DataFile',     '');
            setappdata(hFig, 'SubjectFile',  '');
            setappdata(hFig, 'FigureId',     FigureId);
            %% ===== Add MRI to the figure =====
            % Get figure appdata (surfaces configuration)
            TessInfo = getappdata(hFig, 'Surface');
            % BUILD STRUCTURE
            iTess = length(TessInfo) + 1;
            TessInfo(iTess) = db_template('TessInfo');                       
            TessInfo(iTess).SurfaceFile = MRI_FileName;
            TessInfo(iTess).DataSource.Type     = '';
            TessInfo(iTess).DataSource.FileName = '';
            % LOAD MRI
            sMri = bst_memory('LoadMri', MRI_FileName);
            TessInfo(iTess).Name = 'anatomy';
            % Initial position of the cuts:
            mriSize = size(sMri.Cube(:,:,:,1));
            % vox2ras transformation available: use coordinates (0,0,0)
            mriOrigin = cs_convert(sMri, 'world', 'voxel', [0 0 0]);
            TessInfo(iTess).CutsPosition = round(mriOrigin);
            TessInfo(iTess).SurfSmoothValue = .3;
            % Colormap: depends on the range of values
            TessInfo(iTess).ColormapType = 'anatomy';
            bst_colormaps('AddColormapToFigure', hFig, TessInfo(iTess).ColormapType);
            % Update figure's surfaces list and current surface pointer
            setappdata(hFig, 'Surface',  TessInfo);
            setappdata(hFig, 'iSurface', iTess);
            figure_mri('SetupMri', hFig);
            % Plot MRI
            panel_surface('PlotMri', hFig);
            drawnow;
            % Update panel
            panel_surface('UpdatePanel');
            % Configure the operations that are allowed
            figure_mri('SetFigureStatus', hFig, 1, 1, 0, 0, 1);
            % Select figure Visible
            set(hFig, 'Visible', 'on');
            % Set new callback
            java_setcb(GlobalData.DataSet(iDS).Figure(iFig).Handles.jButtonSave, 'ActionPerformedCallback', @(h,ev)button_save_callback(app, hFig));
            java_setcb(GlobalData.DataSet(iDS).Figure(iFig).Handles.jButtonCancel, 'ActionPerformedCallback', @(h,ev)button_cancel_callback(app, hFig));
            hFig.CloseRequestFcn = @(h,ev)figure_close_callback(app, hFig);
            % Wait for the MRI Viewer to be closed
            if ishandle(hFig)
                waitfor(hFig);
            end
            SCS = GlobalData.SCS;
        end
        % New callback for BST fcuntion.
        function button_save_callback(~, hFig)
            global GlobalData
            [hFig,iFig,iDS] = bst_figures('GetFigure', hFig);
            sMri = panel_surface('GetSurfaceMri', hFig);
            if(isempty(sMri.SCS.NAS))
                warndlg('Please set NAS', 'Warning');
                return
            elseif(isempty(sMri.SCS.LPA))
                warndlg('Please set LPA', 'Warning');
                return
            elseif(isempty(sMri.SCS.RPA))
                warndlg('Please set RPA', 'Warning');
                return
            end
            % Using GlobalData as output.
            GlobalData.SCS = sMri.SCS;
            GlobalData.DataSet(iDS).Figure(iFig).Handles.isModifiedMri = 0;
            % Unload all datasets
            bst_memory('UnloadAll', 'Forced');
        end
        % New callback for BST fcuntion.
        function button_cancel_callback(~, hFig)
            global GlobalData
            [~,iFig,iDS] = bst_figures('GetFigure', hFig);
            GlobalData.DataSet(iDS).Figure(iFig).Handles.isModifiedMri = 0;
            % Unload all datasets
            bst_memory('UnloadAll', 'Forced');
            GlobalData.SCS = [];
        end
        % New callback for BST fcuntion.
        function figure_close_callback(~, hFig)
            global GlobalData
            [~,iFig,iDS] = bst_figures('GetFigure', hFig);
            GlobalData.DataSet(iDS).Figure(iFig).Handles.isModifiedMri = 0;
            % Unload all datasets
            bst_memory('UnloadAll', 'Forced');
            GlobalData.SCS = [];
        end
    end
    
    methods (Access = public)
        function select_file_return(app)
            if(app.Process_isOK && app.select_file_index == 1 && length(app.SelectPath) == 1)
                LoadButtonPushed(app);
            elseif(app.Process_isOK && app.select_file_index == 2)
                AddButton_SubjectPushed(app);
            elseif(app.Process_isOK && app.select_file_index == 3)
                AddButton_StudyPushed(app);
            end
        end
        
        function confirm_return(app)
            if(app.Process_isOK && app.confirm_index == 1)
                OKButtonPushed(app);
            else
                % update subject
                update_subject_name(app);
                % update meg
                update_meg_name(app);
                % updata info in GUI 
                set_db_show(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.UIFigure.Name = 'Load Project';
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            
            % load previous ProjectDir
            if(~isempty(app.CallingApp.ProjectDir))
                app.StudyFileFilterEditField.Value = app.CallingApp.ProjectDir;
                StudyFileFilterEditFieldValueChanged(app);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if(~app.Process_isOK)
                app.confirm_index = 1;
                app.Process_isOK = 0;
                app.GridLayout.Visible = 0;
                check_repeat_subject(app);
                check_repeat_meg(app);
                DialogDB(app);
            elseif(app.Process_isOK)
                app.Process_isOK = 0;
                % update bst protocol (add subject, import anatomy, import meg)
                update_bst(app);
                % make bem surface
                creat_bem_surf(app);
                % copy files
                copy_file(app);
                % save cfg mat file
                save_cfg_file(app);
                % return main GUI
                app.CallingApp.db_subject_data = app.db_subject_data;
                app.CallingApp.State = app.State;
                app.CallingApp.ProjectDir = app.ProjectDir;
                app.CallingApp.GridLayout.Visible = 1;
                app.CallingApp.Interupt = 1;
                app.CallingApp.Process_isOK = 1;
                app.CallingApp.BrainstormDbDir = app.BrainstormDbDir;
                app.CallingApp.db_remote = app.db_remote;
                app.CallingApp.db_process = app.db_process;
                Output = app.CallingApp;
                delete(app);
                if(~isempty(Output.ProjectDir))
                    ProcessLoading(Output);
                end
            end
        end

        % Button pushed function: DataBaseInformationButton
        function DataBaseInformationButtonPushed(app, event)
            if(~app.Process_isOK)
                app.confirm_index = 1;
                app.Process_isOK = 0;
                app.GridLayout.Visible = 0;
                check_repeat_subject(app);
                check_repeat_meg(app);
                DialogDB(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Interupt = 1;
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            delete(app);
        end

        % Value changed function: ListBox_Subject
        function ListBox_SubjectValueChanged(app, event)
            value = app.ListBox_Subject.Value;
            if(isempty(value))
                app.ListBox_Study.Items = {};app.ListBox_Study.ItemsData = [];app.ListBox_Study.Value = {};
            else 
                app.ListBox_Study.Items = app.study_name_show{value};
                app.ListBox_Study.ItemsData = 1:length(app.ListBox_Study.Items);
                if(isempty(app.ListBox_Study.Value) || all(~ismember(app.ListBox_Study.ItemsData, app.ListBox_Study.Value)))
                    app.ListBox_Study.Value = {};
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if(~app.Process_isOK)
                app.Process_isOK = 0;
                app.GridLayout.Visible = 0;
                DialogSelectFile(app, './');
                app.select_file_index = 1;
            else
                app.Process_isOK = 0;
                app.ProjectDir = app.SelectPath{1};
                if(ischar(app.ProjectDir)&&exist(app.ProjectDir, 'dir'))
                    app.State = 0;
                    app.StudyFileFilterEditField.Value = app.ProjectDir;
                    % Get Protocol Dir
                    app.BrainstormDbDir = fullfile(app.ProjectDir, 'bstProtocol');
                    if ~exist(app.BrainstormDbDir, 'dir')
                        mkdir(app.BrainstormDbDir);
                    end
                    WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading','Message','Loading BST Protocol.....','Indeterminate','on');
                    StartFcn(app.BrainstormDbDir);
                    close(WaitBar);
                    set_db_show(app);
                else
                    app.State = -1;
                end
                % define db_subject_data. 
                % subject_status: 0, unchange; 1, new add; 2, unchange -> delete; 3, new add -> delete
                % meg_status: 0, unchange; 1, new add; 2, unchange -> delete; 3, new add -> delete
                if(exist(fullfile(app.ProjectDir, 'cfg.mat'), 'file') && ~isempty(whos('-FILE', fullfile(app.ProjectDir, 'cfg.mat'), 'db_subject_data')))
                    temp = load(fullfile(app.ProjectDir, 'cfg.mat'), 'db_subject_data');
                    app.db_subject_data = temp.db_subject_data;
                else
                    app.db_subject_data = struct('subject_status', 1, 'subject_name', '', 'subject_mri', '', 'subject_fs', '',...
                                                 'subject_scs', struct('NAS', [], 'LPA', [], 'RPA', []), ...
                                                 'meg_name', {}, 'meg_file', {}, 'meg_status', {},...
                                                 'channel_mat', {}, 'results', {}); 
                end
                % updata info in GUI 
                set_db_show(app);
                % load db_remote
                if(exist(fullfile(app.ProjectDir, 'cfg.mat'), 'file') && ~isempty(whos('-FILE', fullfile(app.ProjectDir, 'cfg.mat'), 'db_remote')))
                    temp = load(fullfile(app.ProjectDir, 'cfg.mat'), 'db_remote');
                    app.db_remote = temp.db_remote;
                end
                % load db_process
                if(exist(fullfile(app.ProjectDir, 'cfg.mat'), 'file') && ~isempty(whos('-FILE', fullfile(app.ProjectDir, 'cfg.mat'), 'db_process')))
                    temp = load(fullfile(app.ProjectDir, 'cfg.mat'), 'db_process');
                    app.db_process = temp.db_process;
                end
            end
        end

        % Button pushed function: AddButton_Subject
        function AddButton_SubjectPushed(app, event)
            if(~app.Process_isOK)
                app.Process_isOK = 0;
                app.GridLayout.Visible = 0;
                DialogSelectFile(app, './');
                app.select_file_index = 2;
            else
                app.Process_isOK = 0;
                % check anatomy file
                mri_path = app.SelectPath;
                for i=1:length(mri_path)
                    temp_anatomy_path = mri_path{i};
                    check_anatomy_path(app, temp_anatomy_path);
                end
                % set fiducials in db 
                set_db_fiducials(app);
                % update subject
                update_subject_name(app);
                % update meg
                update_meg_name(app);
                % updata info in  GUI 
                set_db_show(app);
            end
        end

        % Button pushed function: DeleteButton_Subject
        function DeleteButton_SubjectPushed(app, event)
            value = app.ListBox_Subject.Value;
            if(~isempty(value))
                switch app.db_subject_data(value).subject_status
                    case 0
                        app.db_subject_data(value).subject_status = 2;
                    case 1
                        app.db_subject_data(value).subject_status = 3;
                    case 2
                        app.db_subject_data(value).subject_status = 0;
                    case 3
                        app.db_subject_data(value).subject_status = 1;
                end
                % update subject
                update_subject_name(app);
                % update meg
                update_meg_name(app);
                % updata info in  GUI 
                set_db_show(app);
            end
        end

        % Button pushed function: AddButton_Study
        function AddButton_StudyPushed(app, event)
            if(~app.Process_isOK && ~isempty(app.ListBox_Subject.Value))
                app.Process_isOK = 0;
                app.GridLayout.Visible = 0;
                DialogSelectFile(app, './');
                app.select_file_index = 3;
            elseif(app.Process_isOK)
                app.Process_isOK = 0;
                % check meg file
                meg_path = app.SelectPath;
                check_meg_path(app, meg_path);
                % update subject
                update_subject_name(app);
                % update meg
                update_meg_name(app);
                % updata info in  GUI 
                set_db_show(app);
            end
        end

        % Button pushed function: DeleteButton_Study
        function DeleteButton_StudyPushed(app, event)
            value_subj = app.ListBox_Subject.Value;
            if(~isempty(value_subj))
                value_fif = app.ListBox_Study.Value;
                for fif=1:length(value_fif)
                    switch app.db_subject_data(value_subj).meg_status(value_fif(fif))
                        case 0
                            app.db_subject_data(value_subj).meg_status(value_fif(fif)) = 2;
                        case 1
                            app.db_subject_data(value_subj).meg_status(value_fif(fif)) = 3;
                        case 2
                            app.db_subject_data(value_subj).meg_status(value_fif(fif)) = 0;
                        case 3
                            app.db_subject_data(value_subj).meg_status(value_fif(fif)) = 1;
                    end
                end
                % update subject
                update_subject_name(app);
                % update meg
                update_meg_name(app);
                % updata info in GUI 
                set_db_show(app);
            end
        end

        % Value changed function: StudyFileFilterEditField
        function StudyFileFilterEditFieldValueChanged(app, event)
            temp = app.StudyFileFilterEditField.Value;
            if(ischar(temp)&&exist(temp, 'dir'))
                app.ProjectDir = temp;
                app.State = 0;
                app.StudyFileFilterEditField.Value = app.ProjectDir;
                % Get Protocol Dir
                app.BrainstormDbDir = fullfile(app.ProjectDir, 'bstProtocol');
                if ~exist(app.BrainstormDbDir, 'dir')
                    mkdir(app.BrainstormDbDir);
                end
                WaitBar = uiprogressdlg(app.UIFigure,'Title','Loading','Message','Loading BST Protocol.....','Indeterminate','on');
                StartFcn(app.BrainstormDbDir);
                close(WaitBar);
                set_db_show(app);
            else
                app.State = -1;
            end
            % define db_subject_data. 
            % subject_status: 0, unchange; 1, new add; 2, unchange -> delete; 3, new add -> delete
            % meg_status: 0, unchange; 1, new add; 2, unchange -> delete; 3, new add -> delete
            if(exist(fullfile(app.ProjectDir, 'cfg.mat'), 'file') && ~isempty(whos('-FILE', fullfile(app.ProjectDir, 'cfg.mat'), 'db_subject_data')))
                temp = load(fullfile(app.ProjectDir, 'cfg.mat'), 'db_subject_data');
                app.db_subject_data = temp.db_subject_data;
            else
                app.db_subject_data = struct('subject_status', 1, 'subject_name', '', 'subject_mri', '', 'subject_fs', '',...
                                             'subject_scs', struct('NAS', [], 'LPA', [], 'RPA', []), ...
                                             'meg_name', {}, 'meg_file', {}, 'meg_status', {},...
                                             'channel_mat', {}, 'results', {}); 
            end
            % updata info in  GUI 
            set_db_show(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ProjectPanel = uipanel(app.GridLayout);
            app.GridLayout_Project = uigridlayout(app.ProjectPanel);
            app.StudyFileFilterEditField = uieditfield(app.GridLayout_Project, 'text');
            app.StudyFileFilterEditField.ValueChangedFcn = createCallbackFcn(app, @StudyFileFilterEditFieldValueChanged, true);
            app.StudyFileFilterEditFieldLabel = uilabel(app.GridLayout_Project);
            app.StudyFileFilterEditFieldLabel.Text = 'Project Path:';
            app.LoadButton = uibutton(app.GridLayout_Project, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StudyPanel = uipanel(app.GridLayout_Project);
            app.GridLayout_Study = uigridlayout(app.StudyPanel);
            app.AddButton_Study = uibutton(app.GridLayout_Study, 'push');
            app.AddButton_Study.ButtonPushedFcn = createCallbackFcn(app, @AddButton_StudyPushed, true);
            app.AddButton_Study.Text = 'Add';
            app.DeleteButton_Study = uibutton(app.GridLayout_Study, 'push');
            app.DeleteButton_Study.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_StudyPushed, true);
            app.DeleteButton_Study.Text = 'Delete';
            app.ListBox_Study = uilistbox(app.GridLayout_Study);
            app.ListBox_Study.Items = {};
            app.ListBox_Study.Value = {};
            app.MEGFilesLabel = uilabel(app.GridLayout_Study);
            app.MEGFilesLabel.Text = 'MEG Files';
            app.SujectPanel = uipanel(app.GridLayout_Project);
            app.GridLayout_Subject = uigridlayout(app.SujectPanel);
            app.DeleteButton_Subject = uibutton(app.GridLayout_Subject, 'push');
            app.DeleteButton_Subject.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_SubjectPushed, true);
            app.DeleteButton_Subject.Text = 'Delete';
            app.AddButton_Subject = uibutton(app.GridLayout_Subject, 'push');
            app.AddButton_Subject.ButtonPushedFcn = createCallbackFcn(app, @AddButton_SubjectPushed, true);
            app.AddButton_Subject.Text = 'Add';
            app.ListBox_Subject = uilistbox(app.GridLayout_Subject);
            app.ListBox_Subject.Items = {};
            app.ListBox_Subject.Multiselect = 'on';
            app.ListBox_Subject.ValueChangedFcn = createCallbackFcn(app, @ListBox_SubjectValueChanged, true);
            app.ListBox_Subject.Value = {};
            app.SubjectsLabel_Subject = uilabel(app.GridLayout_Subject);
            app.SubjectsLabel_Subject.Text = 'Subjects';
            app.Panel = uipanel(app.GridLayout_Project);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.DataBaseInformationButton = uibutton(app.GridLayout2, 'push');
            app.DataBaseInformationButton.ButtonPushedFcn = createCallbackFcn(app, @DataBaseInformationButtonPushed, true);
            app.DataBaseInformationButton.Text = 'DataBase Information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogLoadProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end