classdef DialogProcessStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        PanelLog            matlab.ui.container.Panel
        GridLayoutLog       matlab.ui.container.GridLayout
        DropDownDevice      matlab.ui.control.DropDown
        TextAreaLog         matlab.ui.control.TextArea
        PanelStatus         matlab.ui.container.Panel
        GridLayoutStatus    matlab.ui.container.GridLayout
        ListBoxFinished     matlab.ui.control.ListBox
        ListBoxDownloaded   matlab.ui.control.ListBox
        ListBoxToDo         matlab.ui.control.ListBox
        ListBoxError        matlab.ui.control.ListBox
        FINSHEDLabel        matlab.ui.control.Label
        DOWNLOADEDLabel     matlab.ui.control.Label
        TOBEPROCESSEDLabel  matlab.ui.control.Label
        ERRORLabel          matlab.ui.control.Label
        TextAreaError       matlab.ui.control.TextArea
        REFRESHButton       matlab.ui.control.Button
        DOWNLOADDATAButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
        db_process;
        log_device;
        status_process;
        sshpass_cmd;
    end
    
    methods (Access = private)
        
        % Refresh log info
        function RefreshDeviceLog(app)
            app.log_device = [];
            for dev=1:length(app.db_process.process_info)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Refresh Log Info','Message',['Device: ',num2str(dev),'/',num2str(length(app.db_process.process_info))], ...
                                         'Value',dev/length(app.db_process.process_info));
                process_info = app.db_process.process_info(dev);
                % for remote/local process
                if(app.db_process.is_remote)
                    % refresh log info
                    log_device_temp = struct('device_name', [], 'device_log', [], 'cfg', [], 'cfg_path', [],  'cmd_refresh_1', [], 'cmd_refresh_2', []);
                    log_device_temp.device_name = [strrep(process_info.ip, '.', '_'), '_Device', num2str(process_info.device)];
                    log_device_temp.cmd_refresh_1 = process_info.cmd.status_refresh_1;
                    log_device_temp.cmd_refresh_2 = process_info.cmd.status_refresh_2;
                    % download log and cfg file
                    status = system(log_device_temp.cmd_refresh_1);
                    if(status == 0)
                        log_device_temp.device_log = GetText(app, strrep(process_info.stdout_dir, process_info.project_dir, ...
                                                                         fullfile(app.CallingApp.ProjectDir, 'RunningData', 'RemoteDB', log_device_temp.device_name)));
                        log_device_temp.cfg_path = fullfile(app.CallingApp.ProjectDir, 'RunningData', 'RemoteDB', log_device_temp.device_name, 'cfg.mat');
                        log_device_temp.cfg = load(log_device_temp.cfg_path);
                    end
                    app.log_device = cat(1, app.log_device, log_device_temp);
                else
                    % refresh log info
                    log_device_temp = struct('device_name', [], 'device_log', [], 'cfg', [], 'cfg_path', []);
                    log_device_temp.device_name = ['Device', num2str(process_info.device)];
                    log_device_temp.device_log = GetText(app, process_info.stdout_dir);
                    log_device_temp.cfg_path = fullfile(process_info.project_dir, 'RunningData', 'LocalDB', ['Device', num2str(process_info.device)], 'cfg.mat');
                    log_device_temp.cfg = load(log_device_temp.cfg_path);
                    app.log_device = cat(1, app.log_device, log_device_temp);
                end
                close(wait_bar);
            end
        end
        
        % Refresh Process status
        function RefreshProcessStatus(app)
            app.status_process = struct('subj_finish', [], 'subj_todo', [], 'subj_finish_dev', [], 'subj_download', [], 'subj_error', [], 'subj_error_message', []);
            for dev=1:length(app.db_process.process_info)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Refresh Process status','Message',['Device: ',num2str(dev),'/',num2str(length(app.db_process.process_info))], ...
                                         'Value',dev/length(app.db_process.process_info));
                process_info = app.db_process.process_info(dev);
                remote_process_info = app.log_device(dev).cfg.db_process.process_info;
                % get process status
                subj_name = {remote_process_info.process_data.subject_name};
                subj_status = cat(1, remote_process_info.process_data.process_status);
                app.status_process.subj_todo = cat(1, app.status_process.subj_todo, subj_name(subj_status == 0));
                app.status_process.subj_finish = cat(1, app.status_process.subj_finish, subj_name(subj_status == 1));
                app.status_process.subj_finish_dev = cat(1, app.status_process.subj_finish_dev, ones(length(find(subj_status==1)), 1) * dev);
                app.status_process.subj_error = cat(1, app.status_process.subj_error, subj_name(subj_status == 3));
                % get error message
                error_text = {};error_index = find(subj_status == 3);
                for i=1:length(error_index)
                    log_dir = strrep(remote_process_info.process_data(error_index(i)).log_dir, remote_process_info.project_dir, ...
                                     fullfile(app.CallingApp.ProjectDir, 'RunningData', 'RemoteDB', app.log_device(dev).device_name));
                    if(exist(log_dir, 'file'))
                        error_text_temp = GetText(app, log_dir);
                    else
                        error_text_temp = {''};
                    end
                    error_text = cat(1, error_text, {error_text_temp});
                end
                app.status_process.subj_error_message = cat(1, app.status_process.subj_error_message, error_text);
                % for remote process
                if(app.db_process.is_remote)
                    app.status_process.subj_download = cat(1, app.status_process.subj_download, subj_name(subj_status == 2));
                end
                close(wait_bar);
            end
        end
        
        % update log info
        function UpdateLogInfo(app)
            % update device
            app.DropDownDevice.Items = {app.log_device.device_name};
            if(isempty(app.DropDownDevice.Value))
                app.DropDownDevice.Value = app.DropDownDevice.Items{1};
            end
            % update device log
            sel_index = find(cellfun(@(x)strcmp(x, app.DropDownDevice.Value), app.DropDownDevice.Items));
            app.TextAreaLog.Value = app.log_device(sel_index).device_log;
        end
        
        % update process status
        function UpdateProcessStatus(app)
            app.ListBoxFinished.Items = app.status_process.subj_finish;
            app.ListBoxDownloaded.Items = app.status_process.subj_download;
            app.ListBoxToDo.Items = app.status_process.subj_todo;
            app.ListBoxError.Items = app.status_process.subj_error;
        end
        
        function text_out = GetText(~, txt_path)
            fid=fopen(txt_path); 
            text_out = {''};
            while ~feof(fid)
                text_out = cat(1, text_out, {char(fgetl(fid))});      
            end
            fclose(fid);
        end
        
        % get remote downlad db: download_dev, download_subj, download_cmd
        function db_download = GetRemoteDownloadDB(app, sel_subj)
            db_download = struct('download_dev', [], 'download_index', [], 'download_cmd', [], 'download_subj', [], 'download_subj_meg', []);
            % get download_dev: downloaded cfg mat for each device
            sel_index = cellfun(@(x)find(cellfun(@(y)strcmp(y, x), app.ListBoxFinished.Items)), sel_subj);
            sel_dev_index = app.status_process.subj_finish_dev(sel_index);
            db_download.download_dev = app.log_device(unique(sel_dev_index));
            % get download_index: downloaded subject index for each device
            db_download.download_index = {};
            for i=1:length(db_download.download_dev)
                dev_subj = {db_download.download_dev(i).cfg.db_process.process_info.process_data.subject_name};
                sel_subj_index_dev = cellfun(@(x)any(cellfun(@(y)strcmp(x, y), sel_subj)), dev_subj);
                db_download.download_index = cat(1, db_download.download_index, sel_subj_index_dev);
            end
            % get download_cmd: downloaded shell cmd of select subject
            % get download_subj: downloaded subject name
            % get download_subj_meg: downloaded meg path of subject
            db_download.download_cmd = {};db_download.download_subj = {};
            for i=1:length(db_download.download_dev)
                download_cmd_temp = {db_download.download_dev(i).cfg.db_process.process_info.process_data(db_download.download_index{i}).post_cmd};
                db_download.download_cmd = cat(2, db_download.download_cmd, download_cmd_temp);
                download_subj_temp = {db_download.download_dev(i).cfg.db_process.process_info.process_data(db_download.download_index{i}).subject_name};
                db_download.download_subj = cat(2, db_download.download_subj, download_subj_temp);
                download_subj_meg_temp = {db_download.download_dev(i).cfg.db_process.process_info.process_data(db_download.download_index{i}).meg_path};
                download_subj_meg_temp = cellfun(@(x)strrep(x, [db_download.download_dev(i).cfg.ProjectDir, filesep], ''), download_subj_meg_temp, 'UniformOutput', false);
                db_download.download_subj_meg = cat(2, db_download.download_subj_meg, download_subj_meg_temp);
            end
        end
        
        % Download Remote Data using shell cmd
        function download_status = DownloadRemoteData(app, download_cmd)
            download_status = zeros(length(download_cmd), 1);
            for i=1:length(download_cmd)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Download Remote Data','Message',['Subject: ',num2str(i),'/',num2str(length(download_cmd))], ...
                                         'Value',i/length(download_cmd));
                status = system(download_cmd{i});
                download_status(i) = status == 0;
                close(wait_bar);
            end
        end
        
        % modifiy remote process status of download subject
        function EditRemotePorcessStatus(app, db_download)
            % only keep successfully downloaded subject
            db_download = GetRemoteDownloadDB(app, db_download.download_subj);
            % generate shell cmd for editing remote process status
            edit_cmd = {};
            for dev=1:length(db_download.download_dev)
                edit_index = db_download.download_index{dev};
                edit_index_str = ['[', num2str(edit_index), ']'];
                process_info = db_download.download_dev(dev).cfg.db_process.process_info;
                matlab_cmd = [process_info.matlab_dir, ' -nosplash -nodesktop -nodisplay -batch ',...
                              '''addpath ',fullfile(process_info.project_dir, 'RunningData', 'Fun', 'GroupProcess'), '; run EditPorcessStatus(', edit_index_str ,')'''];
                ssh_matlab_cmd = [app.sshpass_cmd, ' -p ', process_info.passwd,' ssh ', process_info.user_name,'@', process_info.ip, ' ', ...
                                  '"', 'cd ', process_info.project_dir, ';', matlab_cmd, '"'];
                edit_cmd = cat(2, edit_cmd, ssh_matlab_cmd);
            end
            % edit using shell cmd
            for i=1:length(edit_cmd)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Download Remote Data','Message',['Subject: ',num2str(i),'/',num2str(length(edit_cmd))], ...
                                         'Value',i/length(edit_cmd));
                system(edit_cmd{i});
                close(wait_bar);
            end
        end
        
        % modify cfg of project (meg_file name after tsss)
        function ModifyCfg(app, db_download)
            local_cfg = load(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'));
            for i=1:length(local_cfg.db_subject_data)
                db_subject_data = local_cfg.db_subject_data(i);
                index = find(cellfun(@(x)strcmp(x, db_subject_data.subject_name), db_download.download_subj));
                local_cfg.db_subject_data(i).meg_file = db_download.download_subj_meg{i};
            end
            save(fullfile(app.CallingApp.ProjectDir, 'cfg.mat'), '-struct', 'local_cfg', '-v6');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.UIFigure.Name = 'Process Status';
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            app.sshpass_cmd = app.CallingApp.sshpass_cmd;
            
            % load db_process
            app.db_process = app.CallingApp.db_process;
            if(isempty(app.db_process))
                UIFigureCloseRequest(app);
                return;
            end
            
            % check local/remote
            if(~app.db_process.is_remote)
                app.DOWNLOADDATAButton.Enable = 'off';
                app.ListBoxDownloaded.Enable = 'off';
                app.DOWNLOADEDLabel.Enable = 'off';
            end
            
            % get remote/local process device info
            RefreshDeviceLog(app);
            % get remote/local process status
            RefreshProcessStatus(app);
            % update log info
            UpdateLogInfo(app);
            % update process status
            UpdateProcessStatus(app);
        end

        % Button pushed function: REFRESHButton
        function REFRESHButtonPushed(app, event)
            % get remote/local process device info
            RefreshDeviceLog(app);
            % get remote/local process status
            RefreshProcessStatus(app);
            % update log info
            UpdateLogInfo(app);
            % update process status
            UpdateProcessStatus(app);
        end

        % Value changed function: DropDownDevice
        function DropDownDeviceValueChanged(app, event)
            UpdateLogInfo(app);
        end

        % Button pushed function: DOWNLOADDATAButton
        function DOWNLOADDATAButtonPushed(app, event)
            if(~isempty(app.ListBoxFinished) && app.db_process.is_remote)
                sel_subj = app.ListBoxFinished.Value;
                % get remote download db
                db_download = GetRemoteDownloadDB(app, sel_subj);
                % download remote data
                db_download.download_status = DownloadRemoteData(app, db_download.download_cmd);
                % edit remote process status (for downlad subject) 
                EditRemotePorcessStatus(app, db_download);
                REFRESHButtonPushed(app);
                % modify cfg mat of project (for downlad subject)
                ModifyCfg(app, db_download);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.GridLayout.Visible = 1;
            Output = app.CallingApp;
            delete(app);
            StateUpdata(Output);
        end

        % Value changed function: ListBoxError
        function ListBoxErrorValueChanged(app, event)
            if(~isempty(app.ListBoxError.Value))
                sel_index = find(cellfun(@(x)strcmp(x, app.ListBoxError.Value), app.ListBoxError.Items));
                app.TextAreaError.Value = app.status_process.subj_error_message{sel_index};
            else
                app.TextAreaError.Value = {' '};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PanelLog = uipanel(app.GridLayout);
            app.PanelLog.Title = 'PROCESS LOG';
            app.GridLayoutLog = uigridlayout(app.PanelLog);
            app.DropDownDevice = uidropdown(app.GridLayoutLog);
            app.DropDownDevice.Items = {};
            app.DropDownDevice.ValueChangedFcn = createCallbackFcn(app, @DropDownDeviceValueChanged, true);
            app.DropDownDevice.Value = {};
            app.TextAreaLog = uitextarea(app.GridLayoutLog);
            app.TextAreaLog.Editable = 'off';
            app.PanelStatus = uipanel(app.GridLayout);
            app.PanelStatus.Title = 'PROCESS STATUS';
            app.GridLayoutStatus = uigridlayout(app.PanelStatus);
            app.ListBoxFinished = uilistbox(app.GridLayoutStatus);
            app.ListBoxFinished.Items = {};
            app.ListBoxFinished.Multiselect = 'on';
            app.ListBoxFinished.Value = {};
            app.ListBoxDownloaded = uilistbox(app.GridLayoutStatus);
            app.ListBoxDownloaded.Items = {};
            app.ListBoxDownloaded.Value = {};
            app.ListBoxToDo = uilistbox(app.GridLayoutStatus);
            app.ListBoxToDo.Items = {};
            app.ListBoxToDo.Value = {};
            app.ListBoxError = uilistbox(app.GridLayoutStatus);
            app.ListBoxError.Items = {};
            app.ListBoxError.ValueChangedFcn = createCallbackFcn(app, @ListBoxErrorValueChanged, true);
            app.ListBoxError.Value = {};
            app.FINSHEDLabel = uilabel(app.GridLayoutStatus);
            app.FINSHEDLabel.Text = 'FINSHED';
            app.DOWNLOADEDLabel = uilabel(app.GridLayoutStatus);
            app.DOWNLOADEDLabel.Text = 'DOWNLOADED';
            app.TOBEPROCESSEDLabel = uilabel(app.GridLayoutStatus);
            app.TOBEPROCESSEDLabel.Text = 'TO BE PROCESSED';
            app.ERRORLabel = uilabel(app.GridLayoutStatus);
            app.ERRORLabel.Text = 'ERROR';
            app.TextAreaError = uitextarea(app.GridLayoutStatus);
            app.TextAreaError.Editable = 'off';
            app.REFRESHButton = uibutton(app.GridLayout, 'push');
            app.REFRESHButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHButtonPushed, true);
            app.REFRESHButton.Text = 'REFRESH';
            app.DOWNLOADDATAButton = uibutton(app.GridLayout, 'push');
            app.DOWNLOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNLOADDATAButtonPushed, true);
            app.DOWNLOADDATAButton.Text = 'DOWNLOAD DATA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogProcessStatus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end