classdef DialogSelectFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        CancelButton             matlab.ui.control.Button
        SelectButton             matlab.ui.control.Button
        PanelPresentDir          matlab.ui.container.Panel
        GridLayoutPresentDir     matlab.ui.container.GridLayout
        ListBoxPresentDir        matlab.ui.control.ListBox
        PanelSelectDir           matlab.ui.container.Panel
        GridLayoutSelectDir      matlab.ui.container.GridLayout
        ListBoxSelectDir         matlab.ui.control.ListBox
        PathEditFieldLabel       matlab.ui.control.Label
        PathEditFieldPresentDir  matlab.ui.control.EditField
        OpenButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        PresentPath;
        CallingApp;
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, path_in)
            % Store main app in property for CloseRequestFcn to use
            app.UIFigure.Name = 'Select File';
            % Get path
            if(~exist('path_in', 'var'))
                path_in = pwd;
            end
            temp = dir(path_in);
            if(isempty(temp))
                path_in = pwd;
                temp = dir(path_in);
            end
            app.PresentPath = temp(1).folder;
            app.PathEditFieldPresentDir.Value = app.PresentPath;
            % List Present Path 
            temp = dir(app.PresentPath);temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
            temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
            PathName = cat(2, {'.'}, {'..'}, {temp.name});
            app.ListBoxPresentDir.Items = PathName;
            if(~isempty(app.ListBoxPresentDir.Items))
                app.ListBoxPresentDir.Value = app.ListBoxPresentDir.Items{1};
            end
            % List Select Path
            temp = dir(fullfile(app.PresentPath, app.ListBoxPresentDir.Value));temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
            temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
            PathName = {temp.name};
            app.ListBoxSelectDir.Items = PathName;
            % Seg Position
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SelectPath = [];
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            Output = app.CallingApp;
            delete(app);
            select_file_return(Output);
        end

        % Value changed function: ListBoxPresentDir
        function ListBoxPresentDirValueChanged(app, event)
            % List Select Path
            if(exist(fullfile(app.PresentPath, app.ListBoxPresentDir.Value), 'dir'))
                temp = dir(fullfile(app.PresentPath, app.ListBoxPresentDir.Value));temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                PathName = {temp.name};
                app.ListBoxSelectDir.Items = PathName;
            else
                app.ListBoxSelectDir.Items = {};
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            temp = fullfile(app.PresentPath, app.ListBoxPresentDir.Value);
            if(~isempty(temp)) 
                if(exist(temp, 'dir'))
                    temp = dir(temp);
                    app.PresentPath = temp(1).folder;
                    app.PathEditFieldPresentDir.Value = app.PresentPath;
                    % List Present Path 
                    temp = dir(app.PresentPath);temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                    temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                    PathName = cat(2, {'.'}, {'..'}, {temp.name});
                    app.ListBoxPresentDir.Items = PathName;
                    if(~isempty(app.ListBoxPresentDir.Items))
                        app.ListBoxPresentDir.Value = app.ListBoxPresentDir.Items{1};
                    end
                    % List Select Path
                    temp = dir(fullfile(app.PresentPath, app.ListBoxPresentDir.Value));temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                    temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                    PathName = {temp.name};
                    app.ListBoxSelectDir.Items = PathName;
                end
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % if select ListBoxSelectDir
            if(isempty(app.ListBoxSelectDir.Value))
                temp = {fullfile(app.PresentPath, app.ListBoxPresentDir.Value)};
            else
                temp = cellfun(@(x)fullfile(app.PresentPath, app.ListBoxPresentDir.Value, x), app.ListBoxSelectDir.Value, 'UniformOutput', false);  
            end
            % get all path/dir in temp
            if(~isempty(temp))
                temp_all = {};
                for i=1:length(temp)
                    if(exist(temp{i}, 'dir'))
                        % get abs path
                        temp_temp = dir(temp{i});
                        temp_all = cat(1, temp_all, {temp_temp(1).folder});
                    elseif(exist(temp{i}, 'file'))
                        temp_all = cat(1, temp_all, temp(i));    
                    end
                end
                if(isempty(temp_all))
                    app.CallingApp.SelectPath = [];
                    app.CallingApp.Process_isOK = 0;
                else
                    app.CallingApp.SelectPath = temp_all;
                    app.CallingApp.Process_isOK = 1;
                end
            end
            app.CallingApp.GridLayout.Visible = 1;
            Output = app.CallingApp;
            delete(app);
            select_file_return(Output);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.SelectPath = [];
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.Process_isOK = 0;
            Output = app.CallingApp;
            delete(app);
            select_file_return(Output);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if(strcmp(key, 'g'))
                temp = fullfile(app.PresentPath, app.ListBoxPresentDir.Value);
                if(~isempty(temp)) 
                    if(exist(temp, 'dir'))
                        temp = dir(temp);
                        app.PresentPath = temp(1).folder;
                        app.PathEditFieldPresentDir.Value = app.PresentPath;
                        % List Present Path 
                        temp = dir(app.PresentPath);temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                        temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                        PathName = cat(2, {'.'}, {'..'}, {temp.name});
                        app.ListBoxPresentDir.Items = PathName;
                        if(~isempty(app.ListBoxPresentDir.Items))
                            app.ListBoxPresentDir.Value = app.ListBoxPresentDir.Items{1};
                        end
                        % List Select Path
                        temp = dir(fullfile(app.PresentPath, app.ListBoxPresentDir.Value));temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                        temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                        PathName = {temp.name};
                        app.ListBoxSelectDir.Items = PathName;
                    end
                end
            end
        end

        % Value changed function: PathEditFieldPresentDir
        function PathEditFieldPresentDirValueChanged(app, event)
            temp = app.PathEditFieldPresentDir.Value;
            if(exist(temp, 'dir'))
                temp = dir(temp);
                app.PresentPath = temp(1).folder;
                app.PathEditFieldPresentDir.Value = app.PresentPath;
                % List Present Path 
                temp = dir(app.PresentPath);temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                PathName = cat(2, {'.'}, {'..'}, {temp.name});
                app.ListBoxPresentDir.Items = PathName;
                if(~isempty(app.ListBoxPresentDir.Items))
                    app.ListBoxPresentDir.Value = app.ListBoxPresentDir.Items{1};
                end
                % List Select Path
                temp = dir(fullfile(app.PresentPath, app.ListBoxPresentDir.Value));temp = temp(~cellfun(@(x)strcmp(x(1), '.'), {temp.name}));
                temp = cat(1, temp([temp.isdir]), temp(~[temp.isdir]));
                PathName = {temp.name};
                app.ListBoxSelectDir.Items = PathName;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PanelPresentDir = uipanel(app.GridLayout);
            app.GridLayoutPresentDir = uigridlayout(app.PanelPresentDir);
            app.ListBoxPresentDir = uilistbox(app.GridLayoutPresentDir);
            app.ListBoxPresentDir.Items = {};
            app.ListBoxPresentDir.ValueChangedFcn = createCallbackFcn(app, @ListBoxPresentDirValueChanged, true);
            app.ListBoxPresentDir.Value = {};
            app.PanelSelectDir = uipanel(app.GridLayout);
            app.GridLayoutSelectDir = uigridlayout(app.PanelSelectDir);
            app.ListBoxSelectDir = uilistbox(app.GridLayoutSelectDir);
            app.ListBoxSelectDir.Items = {};
            app.ListBoxSelectDir.Multiselect = 'on';
            app.ListBoxSelectDir.Value = {};
            app.PathEditFieldLabel = uilabel(app.GridLayout);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditFieldPresentDir = uieditfield(app.GridLayout, 'text');
            app.PathEditFieldPresentDir.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldPresentDirValueChanged, true);
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSelectFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end