classdef DialogSelectStudy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        OkButton     matlab.ui.control.Button
        Panel        matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        ListBox      matlab.ui.control.ListBox
    end


    properties (Access = private)
        CallingApp   % Main app object
        StudySelect;
        SpikeHFO_State;
        db_subject_data;
    end
    
    methods (Access = private)
        
        function study_state_name = GetStudyState(app)
            wait_bar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message','Geting State......','Indeterminate','on');
            project_dir = app.CallingApp.ProjectDir;   
            results_dirs = app.db_subject_data.results;
            meg_names = app.db_subject_data.meg_name;
            study_state_name = cell(size(results_dirs, 2), 1);
            app.SpikeHFO_State = ones(size(results_dirs, 2), 1);
            for i=1:size(results_dirs, 2)
                wait_bar = uiprogressdlg(app.UIFigure,'Title','Geting Study State','Message',['Geting State: ',num2str(i),'/',num2str(size(results_dirs, 2))],'value',i/size(results_dirs, 2));
                % Load SaveHFOdetectionResults
                StudyState = [0,0,0];isHFO=1;isSpike=1;
                if(exist(fullfile(project_dir, results_dirs{i},'SaveHFOViewResults.mat'),'file'))
                    if(isempty(whos('HFOIdx','-file',fullfile(project_dir, results_dirs{i},'SaveHFOViewResults.mat'))))
                        StudyState = [1,1,1,0]; 
                        isHFO=0;
                    else
                        app.SpikeHFO_State(i) = 3;
                        StudyState(3) = 1;
                    end
                else
                    StudyState = [1,1,1,0];
                end 
                % Load SaveVirtualSensorResults
                if(~exist(fullfile(project_dir, results_dirs{i},'SaveVirtualSensorResults.mat'),'file')||...
                   isempty(whos('ReconParam','-file',fullfile(project_dir, results_dirs{i},'SaveVirtualSensorResults.mat'))))
                    StudyState = [1,1,0,0]; 
                else
                    StudyState(2) = 1;
                end
                % Load SaveIEDDetectionResults
                if(~exist(fullfile(project_dir, results_dirs{i},'SaveIEDDetectionResults.mat'),'file'))
                    app.SpikeHFO_State(i) = 3;
                    StudyState(1) = 0;
                else
                    StudyState(1) = 1;
                end
                study_state_name{i} = ['[',char('SVH'.*StudyState+'___'.*~StudyState),']: ', meg_names{i}];
                try
                    BsTime = load(fullfile(project_dir, results_dirs{i},'SaveIEDDetectionResults.mat'), 'BsTime');BsTime=BsTime.BsTime;
                    if(isempty(BsTime))
                        isSpike=0;
                        app.SpikeHFO_State(i) = 1;
                    else
                        app.SpikeHFO_State(i) = max(app.SpikeHFO_State(i), 2);
                    end
                catch
                    StudyState = [0,0,0];
                end
                if(isSpike==0)
                    study_state_name{i} = ['No Spike: ', meg_names{i}];
                end
                if(isHFO==0)
                    study_state_name{i} = ['No HFO: ', meg_names{i}];
                end
            end
            close(wait_bar);
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            
            % load db_subject_data
            app.db_subject_data = app.CallingApp.db_subject_select;
            study_state_name = GetStudyState(app);
            app.ListBox.Items = study_state_name;
            app.ListBox.Value = {};
            app.StudySelect = app.ListBox.Value;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            select_index = cellfun(@(x)ismember(x, app.ListBox.Value),app.ListBox.Items);
            app.CallingApp.SelectStudy = select_index;
            app.CallingApp.GridLayout.Visible = 1;
            app.CallingApp.SpikeHFO_State = max(app.SpikeHFO_State(select_index));
            Output = app.CallingApp; 
            % Delete the dialog box 
            delete(app);
            applySelectStudy(Output);           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.GridLayout.Visible = 1;
            % Delete the dialog box 
            delete(app);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.StudySelect = app.ListBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSelectStudy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end