classdef DialogSetParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PanelVirtualSensor              matlab.ui.container.Panel
        VirtualSensorOptionsGridLayout  matlab.ui.container.GridLayout
        SourceSpacePanel                matlab.ui.container.Panel
        SourceSpaceGridLayout           matlab.ui.container.GridLayout
        ResolutionmmEditFieldLabel      matlab.ui.control.Label
        ResolutionmmEditField           matlab.ui.control.NumericEditField
        SourceModelLabel                matlab.ui.control.Label
        SouceOriPanel                   matlab.ui.container.Panel
        SouceOriGridLayout              matlab.ui.container.GridLayout
        BaselineWindowsLabel            matlab.ui.control.Label
        BaselineWindowsEditField        matlab.ui.control.NumericEditField
        IEDWindowsLabel                 matlab.ui.control.Label
        IEDWindowsEditField             matlab.ui.control.NumericEditField
        HFOWindowsmsEditFieldLabel      matlab.ui.control.Label
        HFOWindowsEditField             matlab.ui.control.NumericEditField
        IEDPeakWindowsLabel             matlab.ui.control.Label
        IEDPeakWindowsEditField         matlab.ui.control.NumericEditField
        HFOSegmentsEditFieldLabel       matlab.ui.control.Label
        HFOSegmentsEditField            matlab.ui.control.NumericEditField
        HFOPowerWindowsEditField        matlab.ui.control.NumericEditField
        HFOPowerWindowsEditFieldLabel   matlab.ui.control.Label
        SourceSpaceOrientationLabel     matlab.ui.control.Label
        OkButton                        matlab.ui.control.Button
        PanelPreprocessing              matlab.ui.container.Panel
        GridLayoutPreProcess            matlab.ui.container.GridLayout
        PanelBadSegment                 matlab.ui.container.Panel
        GridLayoutBadSegment            matlab.ui.container.GridLayout
        HighPassNoisyLabel              matlab.ui.control.Label
        HighPassNoisyField              matlab.ui.control.NumericEditField
        LowPassNoisyLabel               matlab.ui.control.Label
        LowPassNoisyEditField           matlab.ui.control.NumericEditField
        ThresholdNoisyEditFieldLabel    matlab.ui.control.Label
        ThresholdNoisyEditField         matlab.ui.control.NumericEditField
        ThresholdJumpEditFieldLabel     matlab.ui.control.Label
        ThresholdJumpEditField          matlab.ui.control.NumericEditField
        BadSegmentCheckBox              matlab.ui.control.CheckBox
        Panel_ICAandTSSS                matlab.ui.container.Panel
        GridLayoutICAandTSSS            matlab.ui.container.GridLayout
        TextAreaFineCal                 matlab.ui.control.TextArea
        TextAreaCrosstalk               matlab.ui.control.TextArea
        FineCalButton                   matlab.ui.control.Button
        CrosstalkButton                 matlab.ui.control.Button
        ThresholdTSSSLabel              matlab.ui.control.Label
        ThreEditField_TSSS              matlab.ui.control.NumericEditField
        LowPassTSSSLabel                matlab.ui.control.Label
        LowPassTSSSEditField            matlab.ui.control.NumericEditField
        TSSSandICAECGandEOGCheckBox     matlab.ui.control.CheckBox
        PanelIEDandHFOFilter            matlab.ui.container.Panel
        GridLayoutIEDandHFOFilter       matlab.ui.container.GridLayout
        HighPassIEDLabel                matlab.ui.control.Label
        HighPassIEDEditField            matlab.ui.control.NumericEditField
        LowPassIEDLabel                 matlab.ui.control.Label
        LowPassIEDEditField             matlab.ui.control.NumericEditField
        HighPassHFOLabel                matlab.ui.control.Label
        HighPassHFOEditField            matlab.ui.control.NumericEditField
        LowPassHFOLabel                 matlab.ui.control.Label
        LowPassHFOEditField             matlab.ui.control.NumericEditField
        IEDandHFOFilterLabel            matlab.ui.control.Label
        PreprocessingButton             matlab.ui.control.StateButton
        VirtualSensorRconstructionButton  matlab.ui.control.StateButton
        IEDDetectionButton              matlab.ui.control.StateButton
        HFODetectionButton              matlab.ui.control.StateButton
        PanelIEDDetection               matlab.ui.container.Panel
        GridLayoutIEDDetection          matlab.ui.container.GridLayout
        ThresholdbasedDetectorLabel     matlab.ui.control.Label
        TemplatematchingDetectorLabel   matlab.ui.control.Label
        PanelThresholdbasedDetector     matlab.ui.container.Panel
        GridLayoutThresholdbasedDetector  matlab.ui.container.GridLayout
        ZscoreWindowsEditFieldLabel     matlab.ui.control.Label
        ZscoreWindowsEditField          matlab.ui.control.NumericEditField
        SlopeThresholdEditFieldLabel    matlab.ui.control.Label
        SlopeThresholdEditField         matlab.ui.control.NumericEditField
        AmplitudeThresholdEditFieldLabel  matlab.ui.control.Label
        AmplitudeThresholdEditField     matlab.ui.control.NumericEditField
        ChannelThresholdEditFieldLabel  matlab.ui.control.Label
        ChannelThresholdEditField       matlab.ui.control.NumericEditField
        SharpThresholdEditFieldLabel    matlab.ui.control.Label
        SharpThresholdEditField         matlab.ui.control.NumericEditField
        DurationThresholdEditFieldLabel  matlab.ui.control.Label
        DurationThresholdEditField      matlab.ui.control.NumericEditField
        PanelTemplateMatching           matlab.ui.container.Panel
        GridLayoutTemplateMatching      matlab.ui.container.GridLayout
        HighAmplitudeEditFieldLabel     matlab.ui.control.Label
        HighAmplitudeEditField          matlab.ui.control.NumericEditField
        TemplateSimilarityEditFieldLabel  matlab.ui.control.Label
        TemplateSimilarityEditField     matlab.ui.control.NumericEditField
        SimilarityThresholdLabel        matlab.ui.control.Label
        SimilarityThresholdEditField    matlab.ui.control.NumericEditField
        SegmentWindowsLabel             matlab.ui.control.Label
        SegmentWindowsEditField         matlab.ui.control.NumericEditField
        HighSlopeEditFieldLabel         matlab.ui.control.Label
        HighSlopeEditField              matlab.ui.control.NumericEditField
        HighSharpEditFieldLabel         matlab.ui.control.Label
        HighSharpEditField              matlab.ui.control.NumericEditField
        HighDurationEditFieldLabel      matlab.ui.control.Label
        HighDurationEditField           matlab.ui.control.NumericEditField
        PanelHFODetection               matlab.ui.container.Panel
        HFODetectionGridLayout          matlab.ui.container.GridLayout
        HFOThresholdbasedDetectorLabel  matlab.ui.control.Label
        PanelHFOThresholdbased          matlab.ui.container.Panel
        GridLayoutHFOThresholdbased     matlab.ui.container.GridLayout
        HFOAmplitudeEditFieldLabel      matlab.ui.control.Label
        HFOAmplitudeEditField           matlab.ui.control.NumericEditField
        HFODurationEditFieldLabel       matlab.ui.control.Label
        HFODurationEditField            matlab.ui.control.NumericEditField
        HFOOscillationEditFieldLabel    matlab.ui.control.Label
        HFOOscillationEditField         matlab.ui.control.NumericEditField
        HFOEntropyEditFieldLabel        matlab.ui.control.Label
        HFOEntropyEditField             matlab.ui.control.NumericEditField
        HFOPowerRatioEditFieldLabel     matlab.ui.control.Label
        HFOPowerRatioEditField          matlab.ui.control.NumericEditField
        PanelHFOClusteringDetector      matlab.ui.container.Panel
        GridLayoutHFOClusteringDetector  matlab.ui.container.GridLayout
        SegmentsEventsEditFieldLabel    matlab.ui.control.Label
        SegmentsEventsEditField         matlab.ui.control.NumericEditField
        TFWindowsEditFieldLabel         matlab.ui.control.Label
        TFWindowsEditField              matlab.ui.control.NumericEditField
        GMMComponentEditFieldLabel      matlab.ui.control.Label
        GMMComponentEditField           matlab.ui.control.NumericEditField
        GMMNinitEditFieldLabel          matlab.ui.control.Label
        GMMNinitEditField               matlab.ui.control.NumericEditField
        GMMCovarianceLabel              matlab.ui.control.Label
        GMMCovarianceEditField          matlab.ui.control.EditField
        GMMWhiteningEditFieldLabel      matlab.ui.control.Label
        GMMWhiteningEditField           matlab.ui.control.NumericEditField
        ClusteringbasedDetectorLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;  
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3)/2-app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2)+app.CallingApp.UIFigure.Position(4)/2-app.UIFigure.Position(4)/2;
            % For all
            app.PreprocessingButton.Value = 1;app.PreprocessingButton.BackgroundColor = [0.64,0.99,0.51];app.PanelPreprocessing.Visible = 'on';
            app.IEDDetectionButton.Value = 1;app.IEDDetectionButton.BackgroundColor = [0.64,0.99,0.51];app.PanelIEDDetection.Visible = 'on';
            app.VirtualSensorRconstructionButton.Value = 1;app.VirtualSensorRconstructionButton.BackgroundColor = [0.64,0.99,0.51];app.PanelVirtualSensor.Visible = 'on';
            app.HFODetectionButton.Value = 1;app.HFODetectionButton.BackgroundColor = [0.64,0.99,0.51];app.PanelHFODetection.Visible = 'on';
            
            % Load Parameters from previous (PreProcess)
            app.CallingApp.PreprocessOpt.isTSSSandICA = 0;
            app.CallingApp.PreprocessOpt.isBad = 1;
            app.CallingApp.PreprocessOpt.BadEventOpt = [];
            app.CallingApp.PreprocessOpt.BadEventOpt.NoisyHighPass = 55; 
            app.CallingApp.PreprocessOpt.BadEventOpt.NoisyLowPass = 240; 
            app.CallingApp.PreprocessOpt.BadEventOpt.NoisyAmplitudeThreshold = 5; 
            app.CallingApp.PreprocessOpt.BadEventOpt.JumpAmplitudeThreshold = 10; 
            app.CallingApp.PreprocessOpt.TSSSOpt = [];
            app.CallingApp.PreprocessOpt.TSSSOpt.ThreTSSS = 7;
            app.CallingApp.PreprocessOpt.TSSSOpt.LowerTSSS = 200;
            app.CallingApp.PreprocessOpt.TSSSOpt.FineCal = 'None';
            app.CallingApp.PreprocessOpt.TSSSOpt.Crosstalk = 'None';
            app.CallingApp.PreprocessOpt.Filter = [];
            app.CallingApp.PreprocessOpt.Filter.HighPassIED = 3;  
            app.CallingApp.PreprocessOpt.Filter.LowPassIED = 80; 
            app.CallingApp.PreprocessOpt.Filter.HighPassHFO = 80; 
            app.CallingApp.PreprocessOpt.Filter.LowPassHFO = 200; 
            
            app.TSSSandICAECGandEOGCheckBox.Value = app.CallingApp.PreprocessOpt.isTSSSandICA;
            app.BadSegmentCheckBox.Value = app.CallingApp.PreprocessOpt.isBad;
            app.TextAreaFineCal.Value = app.CallingApp.PreprocessOpt.TSSSOpt.FineCal;
            app.TextAreaCrosstalk.Value = app.CallingApp.PreprocessOpt.TSSSOpt.Crosstalk;
            app.ThreEditField_TSSS.Value = app.CallingApp.PreprocessOpt.TSSSOpt.ThreTSSS;
            app.LowPassTSSSEditField.Value = app.CallingApp.PreprocessOpt.TSSSOpt.LowerTSSS;
            app.ThresholdNoisyEditField.Value = app.CallingApp.PreprocessOpt.BadEventOpt.NoisyAmplitudeThreshold;
            app.ThresholdJumpEditField.Value = app.CallingApp.PreprocessOpt.BadEventOpt.JumpAmplitudeThreshold;
            app.LowPassNoisyEditField.Value = app.CallingApp.PreprocessOpt.BadEventOpt.NoisyLowPass;
            app.HighPassNoisyField.Value = app.CallingApp.PreprocessOpt.BadEventOpt.NoisyHighPass;
            app.HighPassIEDEditField.Value = app.CallingApp.PreprocessOpt.Filter.HighPassIED;
            app.LowPassIEDEditField.Value = app.CallingApp.PreprocessOpt.Filter.LowPassIED;
            app.HighPassHFOEditField.Value = app.CallingApp.PreprocessOpt.Filter.HighPassHFO;
            app.LowPassHFOEditField.Value = app.CallingApp.PreprocessOpt.Filter.LowPassHFO;
            
            % Load Parameters from previous (IED Detection)
            app.CallingApp.IEDdetectionOpt = [];
            app.CallingApp.IEDdetectionOpt.ZscoreWindows = 100; 
            app.CallingApp.IEDdetectionOpt.SlopeThreshold = 2;  
            app.CallingApp.IEDdetectionOpt.AmplitudeThreshold = 2.25;         
            app.CallingApp.IEDdetectionOpt.SharpnessThreshold = 1.5;
            app.CallingApp.IEDdetectionOpt.DurationThreshold = 0.02; 
            app.CallingApp.IEDdetectionOpt.ChannelThreshold = 5; 
            
            app.CallingApp.IEDdetectionOpt.SegmentWindows = 0.1;
            app.CallingApp.IEDdetectionOpt.HighAmplitude = 0.85; 
            app.CallingApp.IEDdetectionOpt.HighSlope = 0.7; 
            app.CallingApp.IEDdetectionOpt.HighSharp = 0.7; 
            app.CallingApp.IEDdetectionOpt.HighDuration = 0.1;
            app.CallingApp.IEDdetectionOpt.SimilarityThreshold = 0.8;
            app.CallingApp.IEDdetectionOpt.TemplateSimilarityThreshold = 0.9; 
            
            app.ZscoreWindowsEditField.Value = app.CallingApp.IEDdetectionOpt.ZscoreWindows;
            app.AmplitudeThresholdEditField.Value = app.CallingApp.IEDdetectionOpt.AmplitudeThreshold;
            app.SlopeThresholdEditField.Value = app.CallingApp.IEDdetectionOpt.SlopeThreshold;
            app.SharpThresholdEditField.Value = app.CallingApp.IEDdetectionOpt.SharpnessThreshold;
            app.ChannelThresholdEditField.Value = app.CallingApp.IEDdetectionOpt.ChannelThreshold;
            app.DurationThresholdEditField.Value = app.CallingApp.IEDdetectionOpt.DurationThreshold;
            app.SegmentWindowsEditField.Value = app.CallingApp.IEDdetectionOpt.SegmentWindows;
            app.HighAmplitudeEditField.Value = app.CallingApp.IEDdetectionOpt.HighAmplitude;
            app.HighSlopeEditField.Value = app.CallingApp.IEDdetectionOpt.HighSlope;
            app.HighSharpEditField.Value = app.CallingApp.IEDdetectionOpt.HighSharp;
            app.HighDurationEditField.Value = app.CallingApp.IEDdetectionOpt.HighDuration;
            app.TemplateSimilarityEditField.Value = app.CallingApp.IEDdetectionOpt.TemplateSimilarityThreshold;
            app.SimilarityThresholdEditField.Value = app.CallingApp.IEDdetectionOpt.SimilarityThreshold;
            
            % Load Parameters from previous (Virtual Sensor) 
            app.CallingApp.VirtualSensorOpt = [];
            app.CallingApp.VirtualSensorOpt.Volume_Resolutione = 7.5;       
            app.CallingApp.VirtualSensorOpt.Signal_Win = 2;  
            app.CallingApp.VirtualSensorOpt.IED_Win = 0.1;        
            app.CallingApp.VirtualSensorOpt.IEDPeakWin = 0.03;     
            app.CallingApp.VirtualSensorOpt.HFO_Win = 0.1;     
            app.CallingApp.VirtualSensorOpt.HFOSegmentWin = 0.3;     
            app.CallingApp.VirtualSensorOpt.HFOPowerWin = 0.05;   
            
            app.ResolutionmmEditField.Value = app.CallingApp.VirtualSensorOpt.Volume_Resolutione;
            app.BaselineWindowsEditField.Value = app.CallingApp.VirtualSensorOpt.Signal_Win;
            app.IEDWindowsEditField.Value = app.CallingApp.VirtualSensorOpt.IED_Win;
            app.IEDPeakWindowsEditField.Value = app.CallingApp.VirtualSensorOpt.IEDPeakWin;
            app.HFOWindowsEditField.Value = app.CallingApp.VirtualSensorOpt.HFO_Win;
            app.HFOSegmentsEditField.Value = app.CallingApp.VirtualSensorOpt.HFOSegmentWin;
            app.HFOPowerWindowsEditField.Value = app.CallingApp.VirtualSensorOpt.HFOPowerWin;
            
            % Load Parameters from previous (HFO Detection)
            app.CallingApp.HFOdetectionOpt = [];
            app.CallingApp.HFOdetectionOpt.HFOAmplitudeThreshold = 2;
            app.CallingApp.HFOdetectionOpt.HFODurationThreshold = 0.02; 
            app.CallingApp.HFOdetectionOpt.HFOOscillationThreshold = 4; 
            app.CallingApp.HFOdetectionOpt.HFOEntropyThreshold = 1.25; 
            app.CallingApp.HFOdetectionOpt.HFOPowerRatioThreshold = 1.25; 
            app.CallingApp.HFOdetectionOpt.SegmentsEvents = 5; 
            app.CallingApp.HFOdetectionOpt.TFWindows = 0.25; 
            app.CallingApp.HFOdetectionOpt.GMMComponent = 1; 
            app.CallingApp.HFOdetectionOpt.GMMWhitening = 0; 
            app.CallingApp.HFOdetectionOpt.GMMNinit = 10; 
            app.CallingApp.HFOdetectionOpt.GMMCovariance = 'diag'; 
            
            app.HFOAmplitudeEditField.Value = app.CallingApp.HFOdetectionOpt.HFOAmplitudeThreshold;
            app.HFODurationEditField.Value = app.CallingApp.HFOdetectionOpt.HFODurationThreshold;
            app.HFOOscillationEditField.Value = app.CallingApp.HFOdetectionOpt.HFOOscillationThreshold;
            app.HFOEntropyEditField.Value = app.CallingApp.HFOdetectionOpt.HFOEntropyThreshold;
            app.HFOPowerRatioEditField.Value = app.CallingApp.HFOdetectionOpt.HFOPowerRatioThreshold;
            app.SegmentsEventsEditField.Value = app.CallingApp.HFOdetectionOpt.SegmentsEvents;
            app.TFWindowsEditField.Value = app.CallingApp.HFOdetectionOpt.SegmentsEvents;
            app.GMMComponentEditField.Value = app.CallingApp.HFOdetectionOpt.GMMComponent;
            app.GMMWhiteningEditField.Value = app.CallingApp.HFOdetectionOpt.GMMWhitening;
            app.GMMNinitEditField.Value = app.CallingApp.HFOdetectionOpt.GMMNinit;
            app.GMMCovarianceEditField.Value = app.CallingApp.HFOdetectionOpt.GMMCovariance;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Interupt = 1;
            app.CallingApp.GridLayout.Visible = 1;
            Output = app.CallingApp; 
            % Delete the dialog box 
            delete(app); 
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.CallingApp.Interupt = 1;
            app.CallingApp.GridLayout.Visible = 1;
            Output = app.CallingApp; 
            % Delete the dialog box 
            delete(app);
            ProcessReturn(Output);           
        end

        % Value changed function: BadSegmentCheckBox
        function BadSegmentCheckBoxValueChanged(app, event)
            app.CallingApp.PreprocessOpt.isBad = app.BadSegmentCheckBox.Value;
        end

        % Value changed function: HighPassNoisyField
        function HighPassNoisyFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.BadEventOpt.NoisyHighPass = app.HighPassNoisyField.Value;
        end

        % Value changed function: LowPassNoisyEditField
        function LowPassNoisyEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.BadEventOpt.NoisyLowPass = app.LowPassNoisyEditField.Value;
        end

        % Value changed function: ThresholdNoisyEditField
        function ThresholdNoisyEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.BadEventOpt.NoisyAmplitudeThreshold = app.ThresholdNoisyEditField.Value;
        end

        % Value changed function: ThresholdJumpEditField
        function ThresholdJumpEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.BadEventOpt.JumpAmplitudeThreshold = app.ThresholdJumpEditField.Value;
        end

        % Value changed function: TSSSandICAECGandEOGCheckBox
        function TSSSandICAECGandEOGCheckBoxValueChanged(app, event)
            app.CallingApp.PreprocessOpt.isTSSSandICA = app.TSSSandICAECGandEOGCheckBox.Value;
        end

        % Value changed function: TextAreaFineCal
        function TextAreaFineCalValueChanged(app, event)
            value = app.TextAreaFineCal.Value;
            if(exist(value, 'file'))
                app.PreprocessOpt.TSSSOpt.FineCal = value;
            else
                app.TextAreaFineCal.Value = '';
                app.PreprocessOpt.TSSSOpt.FineCal = '';
            end
        end

        % Button pushed function: FineCalButton
        function FineCalButtonPushed(app, event)
            [file,path] = uigetfile('','Select FineCal File For TSSS');
            app.CallingApp.PreprocessOpt.TSSSOpt.FineCal = strrep(fullfile(path, file),'/System/Volumes/Data','');
            app.TextAreaFineCal.Value = app.CallingApp.PreprocessOpt.TSSSOpt.FineCal;
        end

        % Value changed function: TextAreaCrosstalk
        function TextAreaCrosstalkValueChanged(app, event)
            value = app.TextAreaCrosstalk.Value;
            if(exist(value, 'file'))
                app.PreprocessOpt.TSSSOpt.Crosstalk = value;
            else
                app.TextAreaCrosstalk.Value = '';
                app.PreprocessOpt.TSSSOpt.Crosstalk = '';
            end
        end

        % Button pushed function: CrosstalkButton
        function CrosstalkButtonPushed(app, event)
            [file,path] = uigetfile('','Select FineCal File For TSSS');
            app.CallingApp.PreprocessOpt.TSSSOpt.Crosstalk = strrep(fullfile(path, file),'/System/Volumes/Data','');
            app.TextAreaCrosstalk.Value = app.CallingApp.PreprocessOpt.TSSSOpt.Crosstalk;
        end

        % Value changed function: LowPassTSSSEditField
        function LowPassTSSSEditFieldValueChanged2(app, event)
            app.CallingApp.PreprocessOpt.TSSSOpt.LowerTSSS = app.LowPassTSSSEditField.Value;
        end

        % Value changed function: ThreEditField_TSSS
        function ThreEditField_TSSSValueChanged(app, event)
            app.CallingApp.PreprocessOpt.TSSSOpt.ThreTSSS = app.ThreEditField_TSSS.Value;
        end

        % Value changed function: HighPassIEDEditField
        function HighPassIEDEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.Filter.HighPassIED = app.HighPassIEDEditField.Value;
        end

        % Value changed function: HighPassHFOEditField
        function HighPassHFOEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.Filter.HighPassHFO = app.HighPassHFOEditField.Value;
        end

        % Value changed function: LowPassIEDEditField
        function LowPassIEDEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.Filter.LowPassIED = app.LowPassIEDEditField.Value;
        end

        % Value changed function: LowPassHFOEditField
        function LowPassHFOEditFieldValueChanged(app, event)
            app.CallingApp.PreprocessOpt.Filter.LowPassHFO = app.LowPassHFOEditField.Value;
        end

        % Value changed function: ZscoreWindowsEditField
        function ZscoreWindowsEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.ZscoreWindows = app.ZscoreWindowsEditField.Value;
        end

        % Value changed function: SlopeThresholdEditField
        function SlopeThresholdEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.SlopeThreshold = app.SlopeThresholdEditField.Value;
        end

        % Value changed function: AmplitudeThresholdEditField
        function AmplitudeThresholdEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.AmplitudeThreshold = app.AmplitudeThresholdEditField.Value;
        end

        % Value changed function: ChannelThresholdEditField
        function ChannelThresholdEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.ChannelThreshold = app.ChannelThresholdEditField.Value;
        end

        % Value changed function: DurationThresholdEditField
        function DurationThresholdEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.DurationThreshold = app.DurationThresholdEditField.Value;
        end

        % Value changed function: SharpThresholdEditField
        function SharpThresholdEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.SharpnessThreshold = app.SharpThresholdEditField.Value;
        end

        % Value changed function: TemplateSimilarityEditField
        function TemplateSimilarityEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.TemplateSimilarityThreshold = app.TemplateSimilarityEditField.Value;
        end

        % Value changed function: SegmentWindowsEditField
        function SegmentWindowsEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.SegmentWindows = app.SegmentWindowsEditField.Value;
        end

        % Value changed function: HighAmplitudeEditField
        function HighAmplitudeEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.HighAmplitude = app.HighAmplitudeEditField.Value;
        end

        % Value changed function: HighSlopeEditField
        function HighSlopeEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.HighSlope = app.HighSlopeEditField.Value;
        end

        % Value changed function: HighDurationEditField
        function HighDurationEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.HighDuration = app.HighDurationEditField.Value;
        end

        % Value changed function: HighSharpEditField
        function HighSharpEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.HighSharp = app.HighSharpEditField.Value;
        end

        % Value changed function: SimilarityThresholdEditField
        function SimilarityThresholdEditFieldValueChanged(app, event)
            app.CallingApp.IEDdetectionOpt.SimilarityThreshold = app.SimilarityThresholdEditField.Value;
        end

        % Value changed function: ResolutionmmEditField
        function ResolutionmmEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.Volume_Resolutione = app.ResolutionmmEditField.Value;
        end

        % Value changed function: BaselineWindowsEditField
        function BaselineWindowsEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.Signal_Win = app.BaselineWindowsEditField.Value;
        end

        % Value changed function: IEDWindowsEditField
        function IEDWindowsEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.IED_Win = app.IEDWindowsEditField.Value;
        end

        % Value changed function: IEDPeakWindowsEditField
        function IEDPeakWindowsEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.IEDPeakWin = app.IEDPeakWindowsEditField.Value;
        end

        % Value changed function: HFOSegmentsEditField
        function HFOSegmentsEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.HFOSegmentWin = app.HFOSegmentsEditField.Value;
        end

        % Value changed function: HFOWindowsEditField
        function HFOWindowsEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.HFO_Win = app.HFOWindowsEditField.Value;
        end

        % Value changed function: HFOPowerWindowsEditField
        function HFOPowerWindowsEditFieldValueChanged(app, event)
            app.CallingApp.VirtualSensorOpt.HFOPowerWin = app.HFOPowerWindowsEditField.Value;
        end

        % Value changed function: HFOAmplitudeEditField
        function HFOAmplitudeEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.HFOAmplitudeThreshold = app.HFOAmplitudeEditField.Value;
        end

        % Value changed function: HFODurationEditField
        function HFODurationEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.HFODurationThreshold = app.HFODurationEditField.Value;
        end

        % Value changed function: HFOOscillationEditField
        function HFOOscillationEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.HFOOscillationThreshold = app.HFOOscillationEditField.Value;
        end

        % Value changed function: HFOEntropyEditField
        function HFOEntropyEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.HFOEntropyThreshold = app.HFOEntropyEditField.Value;
        end

        % Value changed function: HFOPowerRatioEditField
        function HFOPowerRatioEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.HFOPowerRatioThreshold = app.HFOPowerRatioEditField.Value;
        end

        % Value changed function: GMMComponentEditField
        function GMMComponentEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.GMMComponent = app.GMMComponentEditField.Value;
        end

        % Value changed function: GMMWhiteningEditField
        function GMMWhiteningEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.GMMWhitening = app.GMMWhiteningEditField.Value;
        end

        % Value changed function: GMMNinitEditField
        function GMMNinitEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.GMMNinit = app.GMMNinitEditField.Value;
        end

        % Value changed function: GMMCovarianceEditField
        function GMMCovarianceEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.GMMCovariance = app.GMMCovarianceEditField.Value;
        end

        % Value changed function: TFWindowsEditField
        function TFWindowsEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.TFWindows = app.TFWindowsEditField.Value;
        end

        % Value changed function: SegmentsEventsEditField
        function SegmentsEventsEditFieldValueChanged(app, event)
            app.CallingApp.HFOdetectionOpt.SegmentsEvents = app.SegmentsEventsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PanelVirtualSensor = uipanel(app.GridLayout);
            app.VirtualSensorOptionsGridLayout = uigridlayout(app.PanelVirtualSensor);
            app.SourceSpacePanel = uipanel(app.VirtualSensorOptionsGridLayout);
            app.SourceSpaceGridLayout = uigridlayout(app.SourceSpacePanel);
            app.ResolutionmmEditFieldLabel = uilabel(app.SourceSpaceGridLayout);
            app.ResolutionmmEditFieldLabel.Text = 'Resolution (mm)';
            app.ResolutionmmEditField = uieditfield(app.SourceSpaceGridLayout, 'numeric');
            app.ResolutionmmEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionmmEditFieldValueChanged, true);
            app.ResolutionmmEditField.Value = 7.5;
            app.SourceModelLabel = uilabel(app.VirtualSensorOptionsGridLayout);
            app.SourceModelLabel.Text = 'Source Model';
            app.SouceOriPanel = uipanel(app.VirtualSensorOptionsGridLayout);
            app.SouceOriGridLayout = uigridlayout(app.SouceOriPanel);
            app.BaselineWindowsLabel = uilabel(app.SouceOriGridLayout);
            app.BaselineWindowsLabel.Text = 'Baseline Windows';
            app.BaselineWindowsEditField = uieditfield(app.SouceOriGridLayout, 'numeric');
            app.BaselineWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowsEditFieldValueChanged, true);
            app.BaselineWindowsEditField.Value = 2;
            app.IEDWindowsLabel = uilabel(app.SouceOriGridLayout);
            app.IEDWindowsLabel.Text = 'IED Windows';
            app.IEDWindowsEditField = uieditfield(app.SouceOriGridLayout, 'numeric');
            app.IEDWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @IEDWindowsEditFieldValueChanged, true);
            app.IEDWindowsEditField.Value = 0.15;
            app.HFOWindowsmsEditFieldLabel = uilabel(app.SouceOriGridLayout);
            app.HFOWindowsmsEditFieldLabel.Text = 'HFO Windows';
            app.HFOWindowsEditField = uieditfield(app.SouceOriGridLayout, 'numeric');
            app.HFOWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @HFOWindowsEditFieldValueChanged, true);
            app.HFOWindowsEditField.Value = 0.1;
            app.IEDPeakWindowsLabel = uilabel(app.SouceOriGridLayout);
            app.IEDPeakWindowsLabel.Text = 'IED Peak Windows';
            app.IEDPeakWindowsEditField = uieditfield(app.SouceOriGridLayout, 'numeric');
            app.IEDPeakWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @IEDPeakWindowsEditFieldValueChanged, true);
            app.IEDPeakWindowsEditField.Value = 0.03;
            app.HFOSegmentsEditFieldLabel = uilabel(app.SouceOriGridLayout);
            app.HFOSegmentsEditFieldLabel.Text = 'HFO Segments';
            app.HFOSegmentsEditField = uieditfield(app.SouceOriGridLayout, 'numeric');
            app.HFOSegmentsEditField.ValueChangedFcn = createCallbackFcn(app, @HFOSegmentsEditFieldValueChanged, true);
            app.HFOSegmentsEditField.Value = 0.3;
            app.HFOPowerWindowsEditField = uieditfield(app.SouceOriGridLayout, 'numeric');
            app.HFOPowerWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @HFOPowerWindowsEditFieldValueChanged, true);
            app.HFOPowerWindowsEditField.Value = 0.05;
            app.HFOPowerWindowsEditFieldLabel = uilabel(app.SouceOriGridLayout);
            app.HFOPowerWindowsEditFieldLabel.Text = 'HFO Power Windwos';
            app.SourceSpaceOrientationLabel = uilabel(app.VirtualSensorOptionsGridLayout);
            app.SourceSpaceOrientationLabel.Text = 'Source Space Orientation:';
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.PanelPreprocessing = uipanel(app.GridLayout);
            app.GridLayoutPreProcess = uigridlayout(app.PanelPreprocessing);
            app.PanelBadSegment = uipanel(app.GridLayoutPreProcess);
            app.GridLayoutBadSegment = uigridlayout(app.PanelBadSegment);
            app.HighPassNoisyLabel = uilabel(app.GridLayoutBadSegment);
            app.HighPassNoisyLabel.Text = 'High Pass (Noisy)';
            app.HighPassNoisyField = uieditfield(app.GridLayoutBadSegment, 'numeric');
            app.HighPassNoisyField.ValueChangedFcn = createCallbackFcn(app, @HighPassNoisyFieldValueChanged, true);
            app.HighPassNoisyField.Value = 55;
            app.LowPassNoisyLabel = uilabel(app.GridLayoutBadSegment);
            app.LowPassNoisyLabel.Text = 'Low Pass (Noisy)';
            app.LowPassNoisyEditField = uieditfield(app.GridLayoutBadSegment, 'numeric');
            app.LowPassNoisyEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassNoisyEditFieldValueChanged, true);
            app.LowPassNoisyEditField.Value = 240;
            app.ThresholdNoisyEditFieldLabel = uilabel(app.GridLayoutBadSegment);
            app.ThresholdNoisyEditFieldLabel.Text = 'Threshold (Noisy)';
            app.ThresholdNoisyEditField = uieditfield(app.GridLayoutBadSegment, 'numeric');
            app.ThresholdNoisyEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdNoisyEditFieldValueChanged, true);
            app.ThresholdNoisyEditField.Value = 5;
            app.ThresholdJumpEditFieldLabel = uilabel(app.GridLayoutBadSegment);
            app.ThresholdJumpEditFieldLabel.Text = 'Threshold (Jump)';
            app.ThresholdJumpEditField = uieditfield(app.GridLayoutBadSegment, 'numeric');
            app.ThresholdJumpEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdJumpEditFieldValueChanged, true);
            app.ThresholdJumpEditField.Value = 10;
            app.BadSegmentCheckBox = uicheckbox(app.GridLayoutPreProcess);
            app.BadSegmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @BadSegmentCheckBoxValueChanged, true);
            app.BadSegmentCheckBox.Text = ' Bad Event Detection';
            app.BadSegmentCheckBox.Value = true;
            app.Panel_ICAandTSSS = uipanel(app.GridLayoutPreProcess);
            app.GridLayoutICAandTSSS = uigridlayout(app.Panel_ICAandTSSS);
            app.TextAreaFineCal = uitextarea(app.GridLayoutICAandTSSS);
            app.TextAreaFineCal.ValueChangedFcn = createCallbackFcn(app, @TextAreaFineCalValueChanged, true);
            app.TextAreaFineCal.Value = {'None'};
            app.TextAreaCrosstalk = uitextarea(app.GridLayoutICAandTSSS);
            app.TextAreaCrosstalk.ValueChangedFcn = createCallbackFcn(app, @TextAreaCrosstalkValueChanged, true);
            app.TextAreaCrosstalk.Value = {'None'};
            app.FineCalButton = uibutton(app.GridLayoutICAandTSSS, 'push');
            app.FineCalButton.ButtonPushedFcn = createCallbackFcn(app, @FineCalButtonPushed, true);
            app.FineCalButton.Text = 'FineCal';
            app.CrosstalkButton = uibutton(app.GridLayoutICAandTSSS, 'push');
            app.CrosstalkButton.ButtonPushedFcn = createCallbackFcn(app, @CrosstalkButtonPushed, true);
            app.CrosstalkButton.Text = 'Crosstalk';
            app.ThresholdTSSSLabel = uilabel(app.GridLayoutICAandTSSS);
            app.ThresholdTSSSLabel.Text = 'Threshold (TSSS)';
            app.ThreEditField_TSSS = uieditfield(app.GridLayoutICAandTSSS, 'numeric');
            app.ThreEditField_TSSS.ValueChangedFcn = createCallbackFcn(app, @ThreEditField_TSSSValueChanged, true);
            app.ThreEditField_TSSS.Value = 7;
            app.LowPassTSSSLabel = uilabel(app.GridLayoutICAandTSSS);
            app.LowPassTSSSLabel.Text = 'Low Pass (TSSS)';
            app.LowPassTSSSEditField = uieditfield(app.GridLayoutICAandTSSS, 'numeric');
            app.LowPassTSSSEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassTSSSEditFieldValueChanged2, true);
            app.LowPassTSSSEditField.Value = 200;
            app.TSSSandICAECGandEOGCheckBox = uicheckbox(app.GridLayoutPreProcess);
            app.TSSSandICAECGandEOGCheckBox.ValueChangedFcn = createCallbackFcn(app, @TSSSandICAECGandEOGCheckBoxValueChanged, true);
            app.TSSSandICAECGandEOGCheckBox.Text = 'TSSS and ICA (ECG and EOG)';
            app.TSSSandICAECGandEOGCheckBox.Value = true;
            app.PanelIEDandHFOFilter = uipanel(app.GridLayoutPreProcess);
            app.GridLayoutIEDandHFOFilter = uigridlayout(app.PanelIEDandHFOFilter);
            app.HighPassIEDLabel = uilabel(app.GridLayoutIEDandHFOFilter);
            app.HighPassIEDLabel.Text = 'High Pass (IED)';
            app.HighPassIEDEditField = uieditfield(app.GridLayoutIEDandHFOFilter, 'numeric');
            app.HighPassIEDEditField.Limits = [0 10];
            app.HighPassIEDEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassIEDEditFieldValueChanged, true);
            app.HighPassIEDEditField.Value = 3;
            app.LowPassIEDLabel = uilabel(app.GridLayoutIEDandHFOFilter);
            app.LowPassIEDLabel.Text = 'Low Pass (IED)';
            app.LowPassIEDEditField = uieditfield(app.GridLayoutIEDandHFOFilter, 'numeric');
            app.LowPassIEDEditField.Limits = [10 100];
            app.LowPassIEDEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassIEDEditFieldValueChanged, true);
            app.LowPassIEDEditField.Value = 80;
            app.HighPassHFOLabel = uilabel(app.GridLayoutIEDandHFOFilter);
            app.HighPassHFOLabel.Text = 'High Pass (HFO)';
            app.HighPassHFOEditField = uieditfield(app.GridLayoutIEDandHFOFilter, 'numeric');
            app.HighPassHFOEditField.Limits = [80 200];
            app.HighPassHFOEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassHFOEditFieldValueChanged, true);
            app.HighPassHFOEditField.Value = 80;
            app.LowPassHFOLabel = uilabel(app.GridLayoutIEDandHFOFilter);
            app.LowPassHFOLabel.Text = 'Low Pass (HFO)';
            app.LowPassHFOEditField = uieditfield(app.GridLayoutIEDandHFOFilter, 'numeric');
            app.LowPassHFOEditField.Limits = [80 600];
            app.LowPassHFOEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassHFOEditFieldValueChanged, true);
            app.LowPassHFOEditField.Value = 200;
            app.IEDandHFOFilterLabel = uilabel(app.GridLayoutPreProcess);
            app.IEDandHFOFilterLabel.Text = 'IED and HFO Filter';
            app.PreprocessingButton = uibutton(app.GridLayout, 'state');
            app.PreprocessingButton.Text = 'Preprocessing';
            app.VirtualSensorRconstructionButton = uibutton(app.GridLayout, 'state');
            app.VirtualSensorRconstructionButton.Text = 'Virtual Sensor Rconstruction';
            app.IEDDetectionButton = uibutton(app.GridLayout, 'state');
            app.IEDDetectionButton.Text = 'IED Detection';
            app.HFODetectionButton = uibutton(app.GridLayout, 'state');
            app.HFODetectionButton.Text = 'HFO Detection';
            app.PanelIEDDetection = uipanel(app.GridLayout);
            app.GridLayoutIEDDetection = uigridlayout(app.PanelIEDDetection);
            app.ThresholdbasedDetectorLabel = uilabel(app.GridLayoutIEDDetection);
            app.ThresholdbasedDetectorLabel.Text = 'Threshold-based Detector';
            app.TemplatematchingDetectorLabel = uilabel(app.GridLayoutIEDDetection);
            app.TemplatematchingDetectorLabel.Text = 'Template-matching Detector';
            app.PanelThresholdbasedDetector = uipanel(app.GridLayoutIEDDetection);
            app.GridLayoutThresholdbasedDetector = uigridlayout(app.PanelThresholdbasedDetector);
            app.ZscoreWindowsEditFieldLabel = uilabel(app.GridLayoutThresholdbasedDetector);
            app.ZscoreWindowsEditFieldLabel.Text = 'Z-score Windows';
            app.ZscoreWindowsEditField = uieditfield(app.GridLayoutThresholdbasedDetector, 'numeric');
            app.ZscoreWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @ZscoreWindowsEditFieldValueChanged, true);
            app.ZscoreWindowsEditField.Value = 100;
            app.SlopeThresholdEditFieldLabel = uilabel(app.GridLayoutThresholdbasedDetector);
            app.SlopeThresholdEditFieldLabel.Text = 'Slope Threshold';
            app.SlopeThresholdEditField = uieditfield(app.GridLayoutThresholdbasedDetector, 'numeric');
            app.SlopeThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeThresholdEditFieldValueChanged, true);
            app.SlopeThresholdEditField.Value = 2;
            app.AmplitudeThresholdEditFieldLabel = uilabel(app.GridLayoutThresholdbasedDetector);
            app.AmplitudeThresholdEditFieldLabel.Text = 'Amplitude Threshold';
            app.AmplitudeThresholdEditField = uieditfield(app.GridLayoutThresholdbasedDetector, 'numeric');
            app.AmplitudeThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeThresholdEditFieldValueChanged, true);
            app.AmplitudeThresholdEditField.Value = 2;
            app.ChannelThresholdEditFieldLabel = uilabel(app.GridLayoutThresholdbasedDetector);
            app.ChannelThresholdEditFieldLabel.Text = 'Channel Threshold';
            app.ChannelThresholdEditField = uieditfield(app.GridLayoutThresholdbasedDetector, 'numeric');
            app.ChannelThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelThresholdEditFieldValueChanged, true);
            app.ChannelThresholdEditField.Value = 5;
            app.SharpThresholdEditFieldLabel = uilabel(app.GridLayoutThresholdbasedDetector);
            app.SharpThresholdEditFieldLabel.Text = 'Sharp Threshold';
            app.SharpThresholdEditField = uieditfield(app.GridLayoutThresholdbasedDetector, 'numeric');
            app.SharpThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SharpThresholdEditFieldValueChanged, true);
            app.SharpThresholdEditField.Value = 2;
            app.DurationThresholdEditFieldLabel = uilabel(app.GridLayoutThresholdbasedDetector);
            app.DurationThresholdEditFieldLabel.Text = 'Duration Threshold';
            app.DurationThresholdEditField = uieditfield(app.GridLayoutThresholdbasedDetector, 'numeric');
            app.DurationThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DurationThresholdEditFieldValueChanged, true);
            app.DurationThresholdEditField.Value = 0.02;
            app.PanelTemplateMatching = uipanel(app.GridLayoutIEDDetection);
            app.GridLayoutTemplateMatching = uigridlayout(app.PanelTemplateMatching);
            app.HighAmplitudeEditFieldLabel = uilabel(app.GridLayoutTemplateMatching);
            app.HighAmplitudeEditFieldLabel.Text = 'High Amplitude';
            app.HighAmplitudeEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.HighAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @HighAmplitudeEditFieldValueChanged, true);
            app.HighAmplitudeEditField.Value = 0.8;
            app.TemplateSimilarityEditFieldLabel = uilabel(app.GridLayoutTemplateMatching);
            app.TemplateSimilarityEditFieldLabel.Text = 'Template Similarity';
            app.TemplateSimilarityEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.TemplateSimilarityEditField.ValueChangedFcn = createCallbackFcn(app, @TemplateSimilarityEditFieldValueChanged, true);
            app.TemplateSimilarityEditField.Value = 0.85;
            app.SimilarityThresholdLabel = uilabel(app.GridLayoutTemplateMatching);
            app.SimilarityThresholdLabel.Text = 'Similarity Threshold';
            app.SimilarityThresholdEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.SimilarityThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SimilarityThresholdEditFieldValueChanged, true);
            app.SimilarityThresholdEditField.Value = 0.8;
            app.SegmentWindowsLabel = uilabel(app.GridLayoutTemplateMatching);
            app.SegmentWindowsLabel.Text = 'Segment Windows';
            app.SegmentWindowsEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.SegmentWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentWindowsEditFieldValueChanged, true);
            app.SegmentWindowsEditField.Value = 0.1;
            app.HighSlopeEditFieldLabel = uilabel(app.GridLayoutTemplateMatching);
            app.HighSlopeEditFieldLabel.Text = 'High Slope';
            app.HighSlopeEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.HighSlopeEditField.ValueChangedFcn = createCallbackFcn(app, @HighSlopeEditFieldValueChanged, true);
            app.HighSlopeEditField.Value = 0.8;
            app.HighSharpEditFieldLabel = uilabel(app.GridLayoutTemplateMatching);
            app.HighSharpEditFieldLabel.Text = 'High Sharp';
            app.HighSharpEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.HighSharpEditField.ValueChangedFcn = createCallbackFcn(app, @HighSharpEditFieldValueChanged, true);
            app.HighSharpEditField.Value = 0.8;
            app.HighDurationEditFieldLabel = uilabel(app.GridLayoutTemplateMatching);
            app.HighDurationEditFieldLabel.Text = 'High Duration';
            app.HighDurationEditField = uieditfield(app.GridLayoutTemplateMatching, 'numeric');
            app.HighDurationEditField.ValueChangedFcn = createCallbackFcn(app, @HighDurationEditFieldValueChanged, true);
            app.HighDurationEditField.Value = 0.8;
            app.PanelHFODetection = uipanel(app.GridLayout);
            app.HFODetectionGridLayout = uigridlayout(app.PanelHFODetection);
            app.HFOThresholdbasedDetectorLabel = uilabel(app.HFODetectionGridLayout);
            app.HFOThresholdbasedDetectorLabel.Text = 'Threshold-based Detector';
            app.PanelHFOThresholdbased = uipanel(app.HFODetectionGridLayout);
            app.GridLayoutHFOThresholdbased = uigridlayout(app.PanelHFOThresholdbased);
            app.HFOAmplitudeEditFieldLabel = uilabel(app.GridLayoutHFOThresholdbased);
            app.HFOAmplitudeEditFieldLabel.Text = 'Amplitude Threshold';
            app.HFOAmplitudeEditField = uieditfield(app.GridLayoutHFOThresholdbased, 'numeric');
            app.HFOAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @HFOAmplitudeEditFieldValueChanged, true);
            app.HFOAmplitudeEditField.Value = 2;
            app.HFODurationEditFieldLabel = uilabel(app.GridLayoutHFOThresholdbased);
            app.HFODurationEditFieldLabel.Text = 'Duration Threshold';
            app.HFODurationEditField = uieditfield(app.GridLayoutHFOThresholdbased, 'numeric');
            app.HFODurationEditField.ValueChangedFcn = createCallbackFcn(app, @HFODurationEditFieldValueChanged, true);
            app.HFODurationEditField.Value = 0.02;
            app.HFOOscillationEditFieldLabel = uilabel(app.GridLayoutHFOThresholdbased);
            app.HFOOscillationEditFieldLabel.Text = 'Oscillation Threshold';
            app.HFOOscillationEditField = uieditfield(app.GridLayoutHFOThresholdbased, 'numeric');
            app.HFOOscillationEditField.ValueChangedFcn = createCallbackFcn(app, @HFOOscillationEditFieldValueChanged, true);
            app.HFOOscillationEditField.Value = 4;
            app.HFOEntropyEditFieldLabel = uilabel(app.GridLayoutHFOThresholdbased);
            app.HFOEntropyEditFieldLabel.Text = 'Entropy Threshold';
            app.HFOEntropyEditField = uieditfield(app.GridLayoutHFOThresholdbased, 'numeric');
            app.HFOEntropyEditField.ValueChangedFcn = createCallbackFcn(app, @HFOEntropyEditFieldValueChanged, true);
            app.HFOEntropyEditField.Value = 1.25;
            app.HFOPowerRatioEditFieldLabel = uilabel(app.GridLayoutHFOThresholdbased);
            app.HFOPowerRatioEditFieldLabel.Text = 'P-Ratio Threshold';
            app.HFOPowerRatioEditField = uieditfield(app.GridLayoutHFOThresholdbased, 'numeric');
            app.HFOPowerRatioEditField.ValueChangedFcn = createCallbackFcn(app, @HFOPowerRatioEditFieldValueChanged, true);
            app.HFOPowerRatioEditField.Value = 1.25;
            app.PanelHFOClusteringDetector = uipanel(app.HFODetectionGridLayout);
            app.GridLayoutHFOClusteringDetector = uigridlayout(app.PanelHFOClusteringDetector);
            app.SegmentsEventsEditFieldLabel = uilabel(app.GridLayoutHFOClusteringDetector);
            app.SegmentsEventsEditFieldLabel.Text = 'Segments in Event';
            app.SegmentsEventsEditField = uieditfield(app.GridLayoutHFOClusteringDetector, 'numeric');
            app.SegmentsEventsEditField.Limits = [2 100];
            app.SegmentsEventsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentsEventsEditFieldValueChanged, true);
            app.SegmentsEventsEditField.Value = 5;
            app.TFWindowsEditFieldLabel = uilabel(app.GridLayoutHFOClusteringDetector);
            app.TFWindowsEditFieldLabel.Text = 'HFO TF Windows';
            app.TFWindowsEditField = uieditfield(app.GridLayoutHFOClusteringDetector, 'numeric');
            app.TFWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @TFWindowsEditFieldValueChanged, true);
            app.TFWindowsEditField.Value = 0.25;
            app.GMMComponentEditFieldLabel = uilabel(app.GridLayoutHFOClusteringDetector);
            app.GMMComponentEditFieldLabel.Text = 'GMM Component';
            app.GMMComponentEditField = uieditfield(app.GridLayoutHFOClusteringDetector, 'numeric');
            app.GMMComponentEditField.Limits = [0 1];
            app.GMMComponentEditField.ValueChangedFcn = createCallbackFcn(app, @GMMComponentEditFieldValueChanged, true);
            app.GMMComponentEditField.Value = 1;
            app.GMMNinitEditFieldLabel = uilabel(app.GridLayoutHFOClusteringDetector);
            app.GMMNinitEditFieldLabel.Text = 'GMM N_Init';
            app.GMMNinitEditField = uieditfield(app.GridLayoutHFOClusteringDetector, 'numeric');
            app.GMMNinitEditField.Limits = [1 100];
            app.GMMNinitEditField.ValueChangedFcn = createCallbackFcn(app, @GMMNinitEditFieldValueChanged, true);
            app.GMMNinitEditField.Value = 10;
            app.GMMCovarianceLabel = uilabel(app.GridLayoutHFOClusteringDetector);
            app.GMMCovarianceLabel.Text = 'GMM Covariance';
            app.GMMCovarianceEditField = uieditfield(app.GridLayoutHFOClusteringDetector, 'text');
            app.GMMCovarianceEditField.ValueChangedFcn = createCallbackFcn(app, @GMMCovarianceEditFieldValueChanged, true);
            app.GMMCovarianceEditField.Value = 'diag';
            app.GMMWhiteningEditFieldLabel = uilabel(app.GridLayoutHFOClusteringDetector);
            app.GMMWhiteningEditFieldLabel.Text = 'GMM Whitening';
            app.GMMWhiteningEditField = uieditfield(app.GridLayoutHFOClusteringDetector, 'numeric');
            app.GMMWhiteningEditField.Limits = [0 1];
            app.GMMWhiteningEditField.ValueChangedFcn = createCallbackFcn(app, @GMMWhiteningEditFieldValueChanged, true);
            app.ClusteringbasedDetectorLabel = uilabel(app.HFODetectionGridLayout);
            app.ClusteringbasedDetectorLabel.Text = 'Clustering-based Detector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSetParam(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end